classdef EDS_Grain_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        GrainAnalyzerTab      matlab.ui.container.Tab
        GrainEditField        matlab.ui.control.NumericEditField
        SheetNameEditField    matlab.ui.control.EditField
        SheetNameLabel        matlab.ui.control.Label
        RegionEditField       matlab.ui.control.EditField
        RegionLabel           matlab.ui.control.Label
        ReadOut               matlab.ui.control.Label
        GrainLabel            matlab.ui.control.Label
        SelectGrainsButton    matlab.ui.control.Button
        LoadDataButton        matlab.ui.control.Button
        WriteDataButton       matlab.ui.control.Button
        GrainAxes             matlab.ui.control.UIAxes
        DopingMapTab          matlab.ui.container.Tab
        C2EditField_2Label    matlab.ui.control.Label
        C1EditField_2Label    matlab.ui.control.Label
        MapTypeDropDown       matlab.ui.control.DropDown
        MapTypeDropDownLabel  matlab.ui.control.Label
        SaveMapButton         matlab.ui.control.Button
        NEditField            matlab.ui.control.NumericEditField
        SiEditField           matlab.ui.control.NumericEditField
        SiLabel               matlab.ui.control.Label
        NEditFieldLabel       matlab.ui.control.Label
        C1EditField           matlab.ui.control.NumericEditField
        C2EditField           matlab.ui.control.NumericEditField
        MapAxes               matlab.ui.control.UIAxes
    end

    properties (Access = private)
        g = 0;                                      % counter for number of grains selected (resets up sheet name change)
        
        start_path                                  % start location when uigetfile is called to load data
        write_path                                  % location the table is written to
        excel_filename = 'EDS Grain Analysis.xlsx'; % name of file being written

        x_size
        y_size
        data                                        % three dimensional array of the original B, C and Si intensities

        x                                           % N x 2 dimensional array containing that start and end x position of the user grain selection
        y                                           % N x 2 dimensional array containing that start and end y position of the user grain selection

        at_percent
        silicon

        m = [0.75, -0.45];                          % Scaling factors for C/B and Si/B
        b = [0.83, 0.63];                           % Scaling factors for C/B and Si/B
        atomic_mass = [10.811, 12.011, 28.0855];    % Atomic mass of B, C and Si
    end
    
    methods (Access = private)
        function at_percent = zeta_factor(app, n, data)
            %% Compute average intensity for each grain
            [counts, intensity_ratio, scaling_factor, rel_intensity, wt_fraction, mol, at_percent] = deal(ones(n,3));
        
            % Average the intensities contained within the user specified region and store it in an 
            % N x 3 array where the columns correspond to B, C and Si respectively
            for i = 1:n
                counts(i,1) = mean(data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 1), 'all');
                counts(i,2) = mean(data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 2), 'all');
                counts(i,3) = mean(data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 3), 'all');
            end
    
            %% Apply the Scaling Factors
            for i = 2:3
                % Compute elemental intensity ratios to be used in scaling factor calcs
                intensity_ratio(:,i) = counts(:,i) ./ counts(:,1);
                % Calculate scaling factors for each point along line averaged intensity
                scaling_factor(:,i) = app.b(i-1) * exp(app.m(i-1) .* intensity_ratio(:,i));
                % Apply scaling factors to get corrected relative intensity of each element
                rel_intensity(:,i) = scaling_factor(:,i) .* intensity_ratio(:,i);
            end
            
            % Compute the total intensity
            total_intensity = rel_intensity(:,1) + rel_intensity(:,2) + rel_intensity(:,3);
        
            for i = 1:3
                % Convert corrected intensities to weight fractions
                wt_fraction(:,i) = rel_intensity(:,i) ./ total_intensity;
                % Convert weight fractions to relative mol fraction
                mol(:,i) = wt_fraction(:,i) / app.atomic_mass(i);
            end
        
            % Compute the total number of moles
            total_mols = mol(:,1) + mol(:,2) + mol(:,3);
        
            for i = 1:3
                % Convert relative mol fraction to atomic percents
                at_percent(:,i) = 100 * mol(:,i) ./ total_mols;
            end
        end

        
        function full_excel_filename = write_to_table(app, region)
            %%
            % Write the data to the sheetname specified in the sheetname edit field
            sheetname = app.SheetNameEditField.Value;

            % If the sheetname is empty, data is stored in 'Default'
            if isempty(sheetname)
                sheetname = 'Default';
            % Check if sheetname exceeds Excel's 31 character limit and truncate it
            elseif size(sheetname,2)>31
                sheetname = sheetname(1:31);
            end

            %% Prepare data for export
            % Compute the B to C ratio
            b_to_c_ratio = app.at_percent(:,1) ./ app.at_percent(:,2);

            % Combine the X, Y, Atomic Percent and B to C Ratio columns
            column_data = array2table(num2cell([app.x, app.y, app.at_percent, b_to_c_ratio]));
        
            % Label the data using the user input in the  region edit field
            region_label = {region, blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1)};

            % Create data headers to label the columns
            column_labels = {'X Start', 'X End', 'Y Start', 'Y End', 'B (at.%)', 'C (at.%)', 'Si (at.%)', 'B/C Ratio'}; 

            % Creat a row of blanks to improve readability of written data
            separator = {blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1)};
        
            % Combine data and headers into one big table to write to Excel
            composite_data = [region_label; column_labels; column_data; separator];

            % Define filename to write the data to
            full_excel_filename = fullfile(app.write_path, app.excel_filename);
        
            %% Write data to output Excel spreadsheet
            writetable(composite_data, full_excel_filename, 'Sheet', sheetname, 'WriteVariableNames', false, 'WriteMode', 'append');

            app.ReadOut.Text = append('File Location: ', full_excel_filename);
        end

        function silicon = average_pixels(app, n)
            % Find the average of an nxn box of pixels surrounding a central pixel and replace it with the average 
            if mod(n,2) == 0
                n = n + 1;
            end

            kernel = ones(n);

            for i = 1:3
                app.data(:,:,i) = conv2(app.data(:,:,i), kernel, 'same');
            end

            ij = app.x_size * app.y_size;
            
            %% Compute average intensity for each grain
            [counts, intensity_ratio, scaling_factor, rel_intensity, wt_fraction, mol, at_percent] = deal(ones(ij,3));
            
            for index = 1:ij
                i = mod(index, app.x_size);
                if i == 0
                    i = app.x_size;
                end
                j = ceil(index / app.x_size);
            
                counts(index,1) = app.data(j,i,1);
                counts(index,2) = app.data(j,i,2);
                counts(index,3) = app.data(j,i,3);
            end
            
            %% Apply the Scaling Factors
            for i = 2:3
                % Compute elemental intensity ratios to be used in scaling factor calcs
                intensity_ratio(:,i) = counts(:,i) ./ counts(:,1);
                % Calculate scaling factors for each point along line averaged intensity
                scaling_factor(:,i) = app.b(i-1) * exp(app.m(i-1) .* intensity_ratio(:,i));
                % Apply scaling factors to get corrected relative intensity of each element
                rel_intensity(:,i) = scaling_factor(:,i) .* intensity_ratio(:,i);
            end
            
            % Compute the total intensity
            total_intensity = rel_intensity(:,1) + rel_intensity(:,2) + rel_intensity(:,3);
            
            for i = 1:3
                % Convert corrected intensities to weight fractions
                wt_fraction(:,i) = rel_intensity(:,i) ./ total_intensity;
                % Convert weight fractions to relative mol fraction
                mol(:,i) = wt_fraction(:,i) / app.atomic_mass(i);
            end
            
            % Compute the total number of moles
            total_mols = mol(:,1) + mol(:,2) + mol(:,3);
            
            for i = 1:3
                % Convert relative mol fraction to atomic percents
                at_percent(:,i) = 100 * mol(:,i) ./ total_mols;
            end
            
            % % Compute the B to C ratio
            % b_to_c_ratio = avg_at_percent(:,1) ./ avg_at_percent(:,2);
            
            [boron, carbon, silicon] = deal(NaN(app.y_size,app.x_size));
            
            for index = 1:ij
                i = mod(index, app.x_size);
                if i == 0
                    i = app.x_size;
                end
                j = ceil(index / app.x_size);
            
                boron(j,i) = at_percent(index,1);
                carbon(j,i) = at_percent(index,2);
                silicon(j,i) = at_percent(index,3);
            end
            
            b_to_c_ratio = boron ./ carbon;
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.GrainAxes.Visible = 'off';
            
            % Clear axes
            cla(app.GrainAxes)

            % Restore default properties
            app.ReadOut.Text = '';
        
            %% Prompt user to select 3 data files
            % If the program has just started, start in default folder and 
            % allow only CSV files to be selected
            if app.g == 0
                app.start_path = '*.csv';
            end       

            [filename, path] = uigetfile(app.start_path, 'Select EDS data files', 'MultiSelect', 'on');
            app.UIFigure.Visible = 'on';

            % Determine number of files selected
            number_of_files = length(filename);

            % Turn the figure window back on
            app.UIFigure.Visible = 'on';
        
            % Separate the path into its consituent folders
            folders_in_path = regexp(path,'[\\/]','split');
            num_folders = length(folders_in_path);
        
            % Determine delimiter type since it differs between Mac and Windows
            if contains(path,'/')
                delim = '/';
            elseif contains(path,'\')
                delim = '\';
            end
        
            % Remove the lowest level subfolder from the path
            folders_in_path(num_folders-1:num_folders) = '';

            if app.g == 0
                % The excel file will be written to the Parent folder of subfolder selected in uigetfile
                % and uigetfile will start in the parent folder the next time it is run
                app.write_path = append(strjoin(folders_in_path,delim),delim);
                app.start_path = append(app.write_path,'*.csv');
            else
                % The start_path is updated in case the user navigates to
                % a different directory from the one that was initially selected 
                app.start_path = append(strjoin(folders_in_path,delim),delim,'*.csv');
            end

            %% Read Data
            [app.y_size, app.x_size] = size(readmatrix(strcat(path,filename{1})));
            app.data = zeros(app.y_size,app.x_size,number_of_files);
        
            for i = 1:number_of_files
                % Create path to file
                mapFile = strcat(path,filename{i});
        
                % Read the data file
                app.data(:,:,i) = readmatrix(mapFile);
            end

            %% Compute the Average over the Entire Scan
            app.x = [1 app.x_size];
            app.y = [1 app.y_size];

            app.at_percent = zeta_factor(app, 1, app.data);

            write_to_table(app, 'Full Scan Area');

            % Initialize selection counter and position arrays
            app.g = 1;
            app.x = [];
            app.y = [];
            
            %% Display EDS Map
            imshow(app.data, 'Parent', app.GrainAxes)
            hold(app.GrainAxes,'on')

            % Remove tick labels
            app.GrainAxes.XAxis.TickLabels = {};
            app.GrainAxes.YAxis.TickLabels = {};

            % Scale axes to match the map size
            app.GrainAxes.XLim = [1 app.x_size];
            app.GrainAxes.YLim = [1 app.y_size];

            % Turn on axes
            app.GrainAxes.Visible = 'on';

            app.silicon = average_pixels(app, app.NEditField.Value);
            app.MapAxes.XLim = [1 app.x_size];
            app.MapAxes.YLim = [1 app.y_size];
        end

        % Button pushed function: WriteDataButton
        function WriteDataButtonPushed(app, event)
            notSilicon = app.silicon < app.SiEditField.Value;
            indices = repmat(notSilicon, [1,1,3]);

            b4c_data = NaN(app.y_size,app.x_size,3);
            b4c_data(indices) = app.data(indices);
            
            app.at_percent = zeta_factor(app, app.g - 1, b4c_data);
            
            write_to_table(app, app.RegionEditField.Value);

            cla(app.GrainAxes)
            imshow(app.data, 'Parent', app.GrainAxes)
            hold(app.GrainAxes,'on')

            app.g = 1;
            app.GrainLabel.Text = append('Grain: ', int2str(app.g - 1));

            [app.x, app.y, app.at_percent] = deal([]);

            app.ReadOut.Text = 'Change sheet name before loading new data.';
        end

        % Button pushed function: SelectGrainsButton
        function SelectGrainsButtonPushed(app, event)
            n = app.GrainEditField.Value;
            
            for i = 1:n
                % Record location of first point
                [x_coord(1,1), y_coord(1,1)] = ginput(1);
    
                % Plot the first point for user reference
                pt1 = scatter(x_coord(1,1), y_coord(1,1), 'r', 'filled', 'Parent', app.GrainAxes);
    
                % Record location of second point
                [x_coord(1,2), y_coord(1,2)] = ginput(1);
    
                % Plot the second point for user reference
                pt2 = scatter(x_coord(1,2), y_coord(1,2), 'r', 'filled', 'Parent', app.GrainAxes);
                
                % Determine corner of rectangle
                x_min = min(x_coord); x_max = max(x_coord);
                y_min = min(y_coord); y_max = max(y_coord);
    
                % Compute rectangle dimensions
                w = x_max - x_min;
                h = y_max - y_min;
    
                % Discard user selection if the either point lies outside of the map bounds
                if (x_min < 1) || (y_min < 1) || (x_max > app.x_size) || (y_max > app.y_size)
                    app.ReadOut.Text = 'Grain selection area outside of axis bounds!';
                    delete(pt1)
                    delete(pt2)
                else
                    % Plot rectangle on top of image
                    rectangle('Position', [x_min y_min w h], 'EdgeColor', 'r', 'LineWidth', 2, 'Parent', app.GrainAxes)
            
                    % Store values
                    % Start is top left and end is bottom right since the image origin is top left
                    app.x(app.g,1) = ceil(x_min); app.x(app.g,2) = floor(x_max);
                    app.y(app.g,1) = ceil(y_min); app.y(app.g,2) = floor(y_max);
                    
                    % Increment the counters
                    app.g = app.g + 1;
                    app.GrainLabel.Text = append('Grain: ', int2str(app.g - 1));
                end
            end
        end

        % Value changed function: MapTypeDropDown
        function MapTypeDropDownValueChanged(app, event)
            cla(app.MapAxes)
            app.MapAxes.Color = 'k';
            
            if strcmp(app.MapTypeDropDown.Value, 'Color')
                app.NEditField.Enable = 'on';
                app.SiEditField.Enable = 'off';
                app.C1EditField.Enable = 'on';
                app.C2EditField.Enable = 'on';
    
                silicon_map = imagesc(app.MapAxes,app.silicon);
                set(silicon_map,'AlphaData',~isnan(app.silicon))
    
                app.MapAxes.Color = 'none';
                colormap(app.MapAxes,'jet');
                colorbar(app.MapAxes,'Parent',app.DopingMapTab)
                clim(app.MapAxes,'auto')
    
                colorbar_limits = app.MapAxes.CLim;
                app.C1EditField.Value = colorbar_limits(1);
                app.C2EditField.Value = colorbar_limits(2);
    
            elseif strcmp(app.MapTypeDropDown.Value, 'BW Threshold')
                app.NEditField.Enable = 'on';
                app.SiEditField.Enable = 'on';
                app.C1EditField.Enable = 'off';
                app.C2EditField.Enable = 'off';

                b4c = NaN(app.y_size, app.x_size);
                one = ones(app.y_size, app.x_size);
                b4c(app.silicon < app.SiEditField.Value) = one(app.silicon < app.SiEditField.Value);
                
                b4c_map = imagesc(app.MapAxes,b4c);
                set(b4c_map,'AlphaData',~isnan(b4c))
                colormap(app.MapAxes,'gray');
                colorbar(app.MapAxes,'Parent',app.DopingMapTab)
                clim(app.MapAxes,[0 1])

                cla(app.GrainAxes)
                b4c_map_2 = imagesc(app.GrainAxes,b4c);
                set(b4c_map_2,'AlphaData',~isnan(b4c))
                colormap(app.GrainAxes,'gray');
                clim(app.GrainAxes,[0 1])
    
            else
                app.NEditField.Enable = 'off';
                app.SiEditField.Enable = 'off';
                app.C1EditField.Enable = 'off';
                app.C2EditField.Enable = 'off';
            end
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            app.silicon = average_pixels(app, app.NEditField.Value);

            if strcmp(app.MapTypeDropDown.Value, 'Color')    
                silicon_map = imagesc(app.MapAxes,app.silicon);
                set(silicon_map,'AlphaData',~isnan(app.silicon))
    
                app.MapAxes.Color = 'none';
                colorbar(app.MapAxes,'Parent',app.DopingMapTab)
                clim(app.MapAxes,'auto')
    
                colorbar_limits = app.MapAxes.CLim;
                app.C1EditField.Value = colorbar_limits(1);
                app.C2EditField.Value = colorbar_limits(2);
    
            elseif strcmp(app.MapTypeDropDown.Value, 'BW Threshold')
                b4c = NaN(app.y_size, app.x_size);
                one = ones(app.y_size, app.x_size);
                b4c(app.silicon < app.SiEditField.Value) = one(app.silicon < app.SiEditField.Value);
    
                b4c_map = imagesc(app.MapAxes,b4c);
                set(b4c_map,'AlphaData',~isnan(b4c))
                colormap(app.MapAxes,'gray');
                clim(app.MapAxes,[0 1])

                cla(app.GrainAxes)
                b4c_map_2 = imagesc(app.GrainAxes,b4c);
                set(b4c_map_2,'AlphaData',~isnan(b4c))
                colormap(app.GrainAxes,'gray');
                clim(app.GrainAxes,[0 1])
            end
        end

        % Value changed function: SiEditField
        function SiEditFieldValueChanged(app, event)
            b4c = NaN(app.y_size, app.x_size, 3);
            b4c(app.silicon < app.SiEditField.Value, :) = app.data(app.silicon < app.SiEditField.Value, :);
    
            b4c_map = imagesc(app.MapAxes,b4c);
            set(b4c_map,'AlphaData',~isnan(b4c))
            colormap(app.MapAxes,'gray');
            clim(app.MapAxes,[0 1])

            cla(app.GrainAxes)
            b4c_map_2 = imagesc(app.GrainAxes,b4c);
            set(b4c_map_2,'AlphaData',~isnan(b4c))
            colormap(app.GrainAxes,'gray');
            clim(app.GrainAxes,[0 1])
        end

        % Value changed function: C1EditField
        function C1EditFieldValueChanged(app, event)
            colorbar_limits = app.MapAxes.CLim;
            colorbar_limits(1) = app.C1EditField.Value;
            clim(app.MapAxes, colorbar_limits)
        end

        % Value changed function: C2EditField
        function C2EditFieldValueChanged(app, event)
            new_silicon = NaN(app.y_size, app.x_size);
            new_silicon(app.silicon < app.C2EditField.Value) = app.silicon(app.silicon < app.C2EditField.Value);
            
            silicon_map = imagesc(app.MapAxes,app.silicon);
            set(silicon_map,'AlphaData',~isnan(new_silicon))

            app.MapAxes.Color = 'none';
            
            colorbar_limits = app.MapAxes.CLim;
            colorbar_limits(2) = app.C2EditField.Value;
            clim(app.MapAxes, colorbar_limits)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrainAnalyzerTab = uitab(app.TabGroup);
            app.GrainAnalyzerTab.Title = 'Grain Analyzer';
            app.GrainAxes = uiaxes(app.GrainAnalyzerTab);
            app.GrainAxes.XTick = [];
            app.GrainAxes.YTick = [];
            app.GrainAxes.ZTick = [];
            app.GrainAxes.ZTickLabel = '';
            app.GrainAxes.Visible = 'off';
            app.WriteDataButton = uibutton(app.GrainAnalyzerTab, 'push');
            app.WriteDataButton.ButtonPushedFcn = createCallbackFcn(app, @WriteDataButtonPushed, true);
            app.WriteDataButton.Text = 'Write Data';
            app.LoadDataButton = uibutton(app.GrainAnalyzerTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SelectGrainsButton = uibutton(app.GrainAnalyzerTab, 'push');
            app.SelectGrainsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGrainsButtonPushed, true);
            app.SelectGrainsButton.Text = 'Select Grains';
            app.GrainLabel = uilabel(app.GrainAnalyzerTab);
            app.GrainLabel.Text = 'Grain: 0';
            app.ReadOut = uilabel(app.GrainAnalyzerTab);
            app.ReadOut.Text = 'Change sheet name before loading data.';
            app.RegionLabel = uilabel(app.GrainAnalyzerTab);
            app.RegionLabel.Text = 'Region:';
            app.RegionEditField = uieditfield(app.GrainAnalyzerTab, 'text');
            app.SheetNameLabel = uilabel(app.GrainAnalyzerTab);
            app.SheetNameLabel.Text = 'Sheet Name:';
            app.SheetNameEditField = uieditfield(app.GrainAnalyzerTab, 'text');
            app.GrainEditField = uieditfield(app.GrainAnalyzerTab, 'numeric');
            app.GrainEditField.Limits = [1 Inf];
            app.GrainEditField.Value = 1;
            app.DopingMapTab = uitab(app.TabGroup);
            app.DopingMapTab.Title = 'Doping Map';
            app.MapAxes = uiaxes(app.DopingMapTab);
            app.MapAxes.XTick = [];
            app.MapAxes.YTick = [];
            app.C2EditField = uieditfield(app.DopingMapTab, 'numeric');
            app.C2EditField.Limits = [0 Inf];
            app.C2EditField.ValueChangedFcn = createCallbackFcn(app, @C2EditFieldValueChanged, true);
            app.C2EditField.Enable = 'off';
            app.C1EditField = uieditfield(app.DopingMapTab, 'numeric');
            app.C1EditField.Limits = [0 Inf];
            app.C1EditField.ValueChangedFcn = createCallbackFcn(app, @C1EditFieldValueChanged, true);
            app.C1EditField.Enable = 'off';
            app.NEditFieldLabel = uilabel(app.DopingMapTab);
            app.NEditFieldLabel.Enable = 'off';
            app.NEditFieldLabel.Text = 'N:';
            app.SiLabel = uilabel(app.DopingMapTab);
            app.SiLabel.Enable = 'off';
            app.SiLabel.Text = 'Si:';
            app.SiEditField = uieditfield(app.DopingMapTab, 'numeric');
            app.SiEditField.Limits = [0 Inf];
            app.SiEditField.ValueChangedFcn = createCallbackFcn(app, @SiEditFieldValueChanged, true);
            app.SiEditField.Enable = 'off';
            app.SiEditField.Value = 4;
            app.NEditField = uieditfield(app.DopingMapTab, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Enable = 'off';
            app.NEditField.Value = 25;
            app.SaveMapButton = uibutton(app.DopingMapTab, 'push');
            app.SaveMapButton.Text = 'Save Map';
            app.MapTypeDropDownLabel = uilabel(app.DopingMapTab);
            app.MapTypeDropDownLabel.Text = 'Map Type:';
            app.MapTypeDropDown = uidropdown(app.DopingMapTab);
            app.MapTypeDropDown.Items = {'', 'Color', 'BW Threshold'};
            app.MapTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MapTypeDropDownValueChanged, true);
            app.MapTypeDropDown.Value = '';
            app.C1EditField_2Label = uilabel(app.DopingMapTab);
            app.C1EditField_2Label.Enable = 'off';
            app.C1EditField_2Label.Text = 'C1:';
            app.C2EditField_2Label = uilabel(app.DopingMapTab);
            app.C2EditField_2Label.Enable = 'off';
            app.C2EditField_2Label.Text = 'C2:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDS_Grain_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end