classdef Raman < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        DataTab              matlab.ui.container.Tab
        Image                matlab.ui.control.Image
        LoadImageButton      matlab.ui.control.Button
        ImageEditField       matlab.ui.control.EditField
        LoadStandardButton   matlab.ui.control.Button
        StandardEditField    matlab.ui.control.EditField
        LoadDataButton       matlab.ui.control.Button
        DataEditField        matlab.ui.control.EditField
        LoadPeakFitButton    matlab.ui.control.Button
        PeakFitEditField     matlab.ui.control.EditField
        SpectrumTab          matlab.ui.container.Tab
        ButtonGroup          matlab.ui.container.ButtonGroup
        NoLinesButton        matlab.ui.control.ToggleButton
        B4CButton            matlab.ui.control.ToggleButton
        SiButton             matlab.ui.control.ToggleButton
        SiB6Button           matlab.ui.control.ToggleButton
        SiCButton            matlab.ui.control.ToggleButton
        IndexLabel           matlab.ui.control.Label
        OneDownButton        matlab.ui.control.Button
        OneUpButton          matlab.ui.control.Button
        SaveSpectrumButton   matlab.ui.control.Button
        SpectrumSlider       matlab.ui.control.Slider
        SpectrumAxes         matlab.ui.control.UIAxes
        SurfaceTab           matlab.ui.container.Tab
        EditFieldLabel       matlab.ui.control.Label
        XSlider              matlab.ui.control.Slider
        YSlider              matlab.ui.control.Slider
        EdgeEditField        matlab.ui.control.NumericEditField
        SaveSurfaceButton    matlab.ui.control.Button
        SurfaceAxes          matlab.ui.control.UIAxes
        PhasesTab            matlab.ui.container.Tab
        AnalyzePhasesButton  matlab.ui.control.Button
        HypercubeButton      matlab.ui.control.Button
        SavePhasesButton     matlab.ui.control.Button
        PhaseAxes            matlab.ui.control.UIAxes
        StressTab            matlab.ui.container.Tab
        RowEditField         matlab.ui.control.NumericEditField
        RowLabel             matlab.ui.control.Label
        AnalyzeStressButton  matlab.ui.control.Button
        PhaseLabel           matlab.ui.control.Label
        PhaseDropDown        matlab.ui.control.DropDown
        PeakLabel            matlab.ui.control.Label
        PeakDropDown         matlab.ui.control.DropDown
        C1EditFieldLabel     matlab.ui.control.Label
        C1EditField          matlab.ui.control.NumericEditField
        C2EditFieldLabel     matlab.ui.control.Label
        C2EditField          matlab.ui.control.NumericEditField
        SaveStressButton     matlab.ui.control.Button
        StressAxes           matlab.ui.control.UIAxes
        AverageTab           matlab.ui.container.Tab
        NEditFieldLabel      matlab.ui.control.Label
        NEditField           matlab.ui.control.NumericEditField
        HorizontalButton     matlab.ui.control.Button
        VerticalButton       matlab.ui.control.Button
        SaveDataButton       matlab.ui.control.Button
        SavePlotButton       matlab.ui.control.Button
        AverageAxes          matlab.ui.control.UIAxes
        GrainsTab            matlab.ui.container.Tab
        SelectGrainButton    matlab.ui.control.Button
        SaveDataButton_2     matlab.ui.control.Button
        SaveImageButton      matlab.ui.control.Button
        GrainAxes            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        path                                % location of Raman data (saved figures will be stored here)

        ratio = 1;                          

        standard                            % table of standard spectra

        x_size
        y_size

        wavenumber                          % column vector of spectral bands
        dcube                               % Y x X x Lambda data cube

        lambda_excitation = 532;            % excitation laser wavelength in nm

        wave_start = 130;                   % spectral bands to be analyzed
        wave_end = 1500;                    % spectral bands to be analyzed
        num_bands
        
%         band = [42, 121, 384];
        band = [121, 139, 48];


        spectrum                            % spectrum plot
        surface                             % surface mesh

        grain_map

        phase_map                           

        first_guess = struct('b4c2', [482.0 11.3 532.7 13.2], 'b4c4', [1087.2 88.3], ...
            'si', [528.8 35.3], 'sic', [964.1 10.0], 'sibc1', [250 20], ...
            'sibc2', [482.0 11.3 532.7 13.2], 'sibc4', [1065 88.3], 'sibc5', [1220, 20]);

        peak_window = struct('b4c2', [448.4 575.4], 'b4c4', [1060 1120], ...
            'si', [448.4 615.2], 'sic', [874.9 1053.0], 'sibc1', [230 290], ...
            'sibc2', [448.4 575.4], 'sibc4', [1020 1100], 'sibc5', [1150 1250]);

        fill_color = [0.7 0.7 0.7];         

        peak_fit                            
        pos_peak
        pos_mean
        pos_std

        num_g = 0;
        poly_x;
        poly_y;
        grain_data;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';

            %% Image selection prompt
            [image_file, app.path] = uigetfile({'*.gif; *.jpg; *.jpeg; *.png; *.svg'},'Select image of map region', app.path);
            image_name = fullfile(app.path, image_file);

            app.ImageEditField.Value = image_file;
            app.Image.ImageSource = image_name;
            app.Image.Visible = 'on';

            app.UIFigure.Visible = 'on';

            image = imread(image_name);
            [y_pixels, x_pixels, ~] = size(image);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';

            %% Restore default properties
            % Spectrum Tab
            cla(app.SpectrumAxes)
            app.ButtonGroup.SelectedObject = app.NoLinesButton;
            app.IndexLabel.Text = {'Index: 1', 'X: 1', 'Y: 1'};
            app.SpectrumSlider.Value = 1;

            % Surface Tab
            cla(app.SurfaceAxes)
            app.EdgeEditField.Value = 1;
            app.XSlider.Value = 1;
            app.YSlider.Value = 1;
            app.SurfaceAxes.View = [0 90];

            % Phase Tab
            cla(app.PhaseAxes)

            % Stress Tab
            cla(app.StressAxes)
            app.AnalyzeStressButton.Enable = 'off';
            app.PhaseDropDown.Enable = 'off';
            app.PeakDropDown.Enable = 'off';
            app.RowEditField.Value = 0;
            app.C1EditField.Value = 0;
            app.C2EditField.Value = 0;

            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'}, app.path);

            app.UIFigure.Visible = 'on';
            app.DataEditField.Value = file;

            data_name = fullfile(app.path, file);
            data = readmatrix(data_name);

            %% Determine data array size
            s_size = find(data(2:end,3) == data(1,3), 1, 'first');
            app.x_size = round((data(end,1) - data(1,1)) / (data(s_size + 1,1) - data(1,1)) + 1);
            app.y_size = round((data(end,2) - data(1,2)) / (data(app.x_size*s_size + 1,2) - data(1,2)) + 1);

            % Slow code that has been replaced by the code above
            %             [~,x,~] = unique(data(:,1),'rows');
            %             [~,y,~] = unique(data(:,2),'rows');
            %             [~,s,~] = unique(data(:,3),'rows');
            %
            %             [app.x_size, ~] = size(x);
            %             [app.y_size, ~] = size(y);
            %             [s_size, ~] = size(s);

            app.ratio = app.x_size / app.y_size;

            %% Convert raw data to usable format
            % Store Raman shift
            app.wavenumber = data(s_size:-1:1,3);

            % Initialize the cell that stores the Raman data
            app.dcube = zeros(app.y_size,app.x_size,s_size);

            for j = 1:app.y_size
                for i = 1:app.x_size
                    a = (i-1)*s_size + (j-1)*app.x_size*s_size + 1;
                    b = i*s_size + (j-1)*app.x_size*s_size;

                    app.dcube(j,i,:) = data(b:-1:a,4);
                end
            end

            [~, start_index] = min(abs(app.wavenumber - app.wave_start));
            [~, end_index] = min(abs(app.wavenumber - app.wave_end));

            app.num_bands = end_index - start_index + 1;

            app.wavenumber = app.wavenumber(start_index:end_index);
            app.dcube = normalize(app.dcube(:,:,start_index:end_index),3,'range');

            %% Setup Spectrum Tab
            % Determine map area (total number of indices)
            app.SpectrumSlider.Limits = [1 app.x_size * app.y_size];

            %Start Plot
            hold(app.SpectrumAxes, 'on')
            app.spectrum = plot(app.wavenumber, squeeze(app.dcube(1,1,:)), 'b', 'LineWidth', 2, 'Parent', app.SpectrumAxes);

            % Title
            app.SpectrumAxes.Title.String = '\textbf{Raman Spectrum Viewer}';
            app.SpectrumAxes.Title.Interpreter = 'latex';
            app.SpectrumAxes.Title.FontSize = 18;

            % X Axis
            app.SpectrumAxes.XLabel.String = '\textbf{Raman Shift (cm$^{-1}$)}';
            app.SpectrumAxes.XLabel.Interpreter = 'latex';
            app.SpectrumAxes.XLabel.FontSize = 14;

            % Y Axis
            app.SpectrumAxes.YLabel.String = '\textbf{Intensity}';
            app.SpectrumAxes.YLabel.Interpreter = 'latex';
            app.SpectrumAxes.YLabel.FontSize = 14;

            % Axis Limits
            app.SpectrumAxes.XLim = [app.wavenumber(1) app.wavenumber(end)];
            app.SpectrumAxes.YLim = [0 1];

            % Turn on axes
            app.SpectrumAxes.Visible = 'on';


            %% Setup Surface Tab
            % Turn off the x and y slider if the scan is one dimensional
            if app.x_size == 1 || app.y_size == 1
                app.XSlider.Visible = 'off';
                app.YSlider.Visible = 'off';

                % Set the slider limits
            else
                app.XSlider.Limits = [1 app.x_size];
                app.YSlider.Limits = [1 app.y_size];

                % Adjust major and minor x ticks if there are few x points
                if app.x_size < 50
                    app.XSlider.MinorTicks = 1:1:app.x_size;
                    app.XSlider.MajorTicks = 1:1:app.x_size;
                else
                    app.XSlider.MajorTicksMode = 'auto';
                    app.XSlider.MajorTickLabelsMode = 'auto';
                    app.XSlider.MinorTicksMode = 'auto';
                end

                % Adjust major and minor y ticks if there are few y points
                if app.y_size < 50
                    app.YSlider.MinorTicks = 1:1:app.y_size;
                    app.YSlider.MajorTicks = 1:1:app.y_size;
                else
                    app.YSlider.MajorTicksMode = 'auto';
                    app.YSlider.MajorTickLabelsMode = 'auto';
                    app.YSlider.MinorTicksMode = 'auto';
                end
            end

            %% 3D Mesh of Line Scan
            % Colored faces with white edges

            % Store the wavenumber and intensity of the x data if the plot is a line scan in x
            if app.y_size == 1
                X_Index = repmat(1:app.x_size, app.num_bands, 1);
                Wavenumber = repmat(app.wavenumber, [1 app.x_size]);
                Intensity = squeez(app.dcube(1,:,:)).';

                app.surface = mesh(X_Index, Wavenumber, Intensity, 'Parent', app.SurfaceAxes);

                app.SurfaceAxes.XLabel.String = '\textbf{X Index}';
                app.SurfaceAxes.YLabel.String = '\textbf{Raman Shift (cm$^{-1}$)}';

                app.SurfaceAxes.XLim = [1 app.x_size];
                app.SurfaceAxes.YLim = [app.wavenumber(1) app.wavenumber(end)];

                % Otherwise store the wavenumeber and intensity of the y data

            else
                Y_Index = repmat(1:app.y_size, app.num_bands, 1);
                Wavenumber = repmat(app.wavenumber, [1 app.y_size]);
                Intensity = squeeze(app.dcube(:,1,:)).';

                app.surface = mesh(Wavenumber, Y_Index, Intensity, 'Parent', app.SurfaceAxes);

                app.SurfaceAxes.XLabel.String = '\textbf{Raman Shift (cm$^{-1}$)}';
                app.SurfaceAxes.YLabel.String = '\textbf{Y Index}';

                app.SurfaceAxes.XLim = [app.wavenumber(1) app.wavenumber(end)];
                app.SurfaceAxes.YLim = [1 app.y_size];
            end

            app.surface.FaceColor = 'interp';
            app.surface.EdgeColor = 'w';
            app.surface.EdgeAlpha = app.EdgeEditField.Value;

            app.SurfaceAxes.Title.String = '\textbf{Raman Surface}';
            app.SurfaceAxes.Title.Interpreter = 'latex';
            app.SurfaceAxes.Title.FontSize = 18;

            app.SurfaceAxes.XLabel.Interpreter = 'latex';
            app.SurfaceAxes.XLabel.FontSize = 14;

            app.SurfaceAxes.YLabel.Interpreter = 'latex';
            app.SurfaceAxes.YLabel.FontSize = 14;

            app.SurfaceAxes.ZLabel.String = '\textbf{Intensity}';
            app.SurfaceAxes.ZLabel.Interpreter = 'latex';
            app.SurfaceAxes.ZLabel.FontSize = 14;

            app.SurfaceAxes.ZLim = [0 1];

            % Turn on axes
            app.SurfaceAxes.Visible = 'on';

            %% Grain Visualization
            for i = 1:3
                app.grain_map(:,:,i) = histeq(app.dcube(:,:,app.band(i)));
            end

            imshow(app.grain_map, 'Parent', app.GrainAxes);
            pbaspect(app.GrainAxes, [app.ratio 1 1])
        end

        % Button pushed function: LoadStandardButton
        function LoadStandardButtonPushed(app, event)
            %% Standard Selection Prompt
            app.UIFigure.Visible = 'off';
            [file, temp_path] = uigetfile({'*.txt'});

            app.UIFigure.Visible = 'on';
            app.StandardEditField.Value = file;

            standard_name = fullfile(temp_path, file);
            app.standard = readtable(standard_name);

            app.standard = flipud(app.standard);

            [~, start_index] = min(abs(app.standard.Wavenumber - app.wave_start));
            [~, end_index] = min(abs(app.standard.Wavenumber - app.wave_end));

            app.standard = app.standard(start_index:end_index, :);
        end

        % Button pushed function: LoadPeakFitButton
        function LoadPeakFitButtonPushed(app, event)
            app.UIFigure.Visible = 'off';

            %% Restore default properties
            % Spectrum Tab
            cla(app.SpectrumAxes)
            app.ButtonGroup.SelectedObject = app.NoLinesButton;
            app.IndexLabel.Text = {'Index: 1', 'X: 1', 'Y: 1'};
            app.SpectrumSlider.Value = 1;

            % Surface Tab
            cla(app.SurfaceAxes)
            app.EdgeEditField.Value = 1;
            app.XSlider.Value = 1;
            app.YSlider.Value = 1;
            app.SurfaceAxes.View = [0 90];

            % Phase Tab
            cla(app.PhaseAxes)

            % Stress Tab
            cla(app.StressAxes)
            app.AnalyzeStressButton.Enable = 'off';
            app.PhaseDropDown.Enable = 'off';
            app.PeakDropDown.Enable = 'off';
            app.RowEditField.Value = 0;
            app.C1EditField.Value = 0;
            app.C2EditField.Value = 0;

            %% Standard Selection Prompt
            [file, app.path] = uigetfile({'*.txt'}, app.path);

            app.UIFigure.Visible = 'on';
            app.PeakFitEditField.Value = file;

            fit_data_name = fullfile(app.path, file);
            peak_import = table2array(readtable(fit_data_name));

            app.x_size = max(peak_import(:,2));
            app.y_size = max(peak_import(:,3));
            app.ratio = app.x_size / app.y_size;

            app.phase_map = NaN(app.y_size, app.x_size);
            app.peak_fit = cell(app.y_size,app.x_size);

            table_row = 1;

            for j = 1:app.y_size
                for i = 1:app.x_size
                    if peak_import(table_row,1) == 1
                        height = peak_import(table_row,4);
                        app.phase_map(j,i) = peak_import(table_row,1);
                        app.peak_fit(j,i) = mat2cell(peak_import(table_row:table_row + height - 1,5:9),height,5);
                        table_row = table_row + height + 1;

                    elseif peak_import(table_row,1) == 4
                        height = peak_import(table_row,4);
                        app.phase_map(j,i) = peak_import(table_row,1);
                        app.peak_fit(j,i) = mat2cell(peak_import(table_row:table_row + height - 1,5:9),height,5);
                        table_row = table_row + height + 1;

                    elseif peak_import(table_row,1) == 5
                        height = peak_import(table_row,4);
                        app.phase_map(j,i) = peak_import(table_row,1);
                        app.peak_fit(j,i) = mat2cell(peak_import(table_row:table_row + height - 1,5:9),height,5);
                        table_row = table_row + height + 1;

                    else
                        app.phase_map(j,i) = peak_import(table_row,1);
                        table_row = table_row + 2;
                    end
                end
            end

            %% Phase Map
            imagesc(app.PhaseAxes,app.phase_map)
            pbaspect(app.PhaseAxes,[app.ratio 1 1])

            app.PhaseAxes.Colormap = jet(5);

            ticks = linspace(1,5,5);
            labels = {'B4C','Si','SiB6','SiC','SiBC'};

            colorbar(app.PhaseAxes,'Parent',app.PhasesTab,'Ticks',ticks, 'TickLabels',labels, 'YDir', 'reverse');
            clim(app.PhaseAxes, [0.5 5.5])

            app.PhaseAxes.XLim = [1 app.x_size];
            app.PhaseAxes.YLim = [1 app.y_size];

            app.PhaseAxes.Visible = 'on';
            app.AnalyzeStressButton.Enable = 'on';

            app.PhaseDropDown.Enable = 'on';

            %% Line Average
            app.pos_peak = NaN(app.y_size,app.x_size);

            for j = 1:app.y_size
                for i = 1:app.x_size
                    if app.phase_map(j,i) == 1
                        app.pos_peak(j,i) = app.peak_fit{j,i}(3,1);

                    elseif app.phase_map(j,i) == 5
                            app.pos_peak(j,i) = app.peak_fit{j,i}(4,1);
                    end
                end
            end
        end

        % Value changing function: SpectrumSlider
        function SpectrumSliderValueChanging(app, event)
            % Delete old spectrum
            delete(app.spectrum)

            % Obtain updated index
            index = round(event.Value);

            % Determine x and y from the index
            i = mod(index, app.x_size);
            if i == 0
                i = app.x_size;
            end
            j = ceil(index / app.x_size);

            % Update the index label
            Index = num2str(index); Index = append('Index: ', Index);
            X = append('X: ', num2str(i));
            Y = append('Y: ', num2str(j));
            app.IndexLabel.Text = {Index, X, Y};

            % Update the plot
            app.spectrum = plot(app.wavenumber, squeeze(app.dcube(j,i,:)), 'b', 'LineWidth', 2, 'Parent', app.SpectrumAxes);
        end

        % Button pushed function: OneUpButton
        function OneUpButtonPushed(app, event)
            % Delete old spectrum
            delete(app.spectrum)

            % Obtain current index and increment one up if the index is less than the plot area
            index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
            if index < app.x_size * app.y_size
                index = index + 1;
            end

            % Update the slider with the new index
            app.SpectrumSlider.Value = index;

            % Determine x and y from the index
            i = mod(index, app.x_size);
            if i == 0
                i = app.x_size;
            end
            j = ceil(index / app.x_size);

            % Update the index label
            Index = num2str(index); Index = append('Index: ', Index);
            X = append('X: ', num2str(i));
            Y = append('Y: ', num2str(j));
            app.IndexLabel.Text = {Index, X, Y};

            % Update the plot
            app.spectrum = plot(app.wavenumber, squeeze(app.dcube(j,i,:)), 'b', 'LineWidth', 2, 'Parent', app.SpectrumAxes);
        end

        % Button pushed function: OneDownButton
        function OneDownButtonPushed(app, event)
            % Delete old spectrum
            delete(app.spectrum)

            % Obtain current index and increment one down if the index is greater than 1
            index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
            if index > 1
                index = index - 1;
            end

            % Update the slider with the new index
            app.SpectrumSlider.Value = index;

            % Determine x and y from the index
            i = mod(index, app.x_size);
            if i == 0
                i = app.x_size;
            end
            j = ceil(index / app.x_size);

            % Update the index label
            Index = num2str(index); Index = append('Index: ', Index);
            X = append('X: ', num2str(i));
            Y = append('Y: ', num2str(j));
            app.IndexLabel.Text = {Index, X, Y};

            % Update the plot
            app.spectrum = plot(app.wavenumber, squeeze(app.dcube(j,i,:)), 'b', 'LineWidth', 2, 'Parent', app.SpectrumAxes);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % Store selected button
            selectedButton = app.ButtonGroup.SelectedObject;

            % Clear axes
            cla(app.SpectrumAxes)

            % Obtain current index
            index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');

            % Determine x and y from the index
            i = mod(index, app.x_size);
            if i == 0
                i = app.x_size;
            end
            j = ceil(index / app.x_size);

            % Update the plot
            app.spectrum = plot(app.wavenumber, squeeze(app.dcube(j,i,:)), 'b', 'LineWidth', 2, 'Parent', app.SpectrumAxes);

            % If the B4C button is selected, plot the lines corresponding to its peak search intervals
            if strcmp(selectedButton.Text, 'B4C')
                plot([app.peak_window.b4c2(1) app.peak_window.b4c2(1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                plot([app.peak_window.b4c2(2) app.peak_window.b4c2(2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                fill([app.peak_window.b4c2(1) app.peak_window.b4c2(2) app.peak_window.b4c2(2) app.peak_window.b4c2(1)], [0 0 1 1], app.fill_color, 'FaceAlpha',0.3, 'Parent', app.SpectrumAxes)

                plot([app.peak_window.b4c4(1) app.peak_window.b4c4(1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                plot([app.peak_window.b4c4(2) app.peak_window.b4c4(2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                fill([app.peak_window.b4c4(1) app.peak_window.b4c4(2) app.peak_window.b4c4(2) app.peak_window.b4c4(1)], [0 0 1 1], app.fill_color, 'FaceAlpha',0.3, 'Parent', app.SpectrumAxes)

                % If the Si button is selected, plot the lines corresponding to its peak search intervals
            elseif strcmp(selectedButton.Text, 'Si')
                plot([app.peak_window.si(1) app.peak_window.si(1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                plot([app.peak_window.si(2) app.peak_window.si(2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                fill([app.peak_window.si(1) app.peak_window.si(2) app.peak_window.si(2) app.peak_window.si(1)], [0 0 1 1], app.fill_color, 'FaceAlpha',0.3, 'Parent', app.SpectrumAxes)

                % If the SiB6 button is selected, plot the lines corresponding to its peak search intervals
                %             elseif strcmp(selectedButton.Text, 'SiB6')
                %                 search_loc = [770,810; 950,990];
                %
                %                 for l = 1:size(search_loc,1)
                %                     plot([search_loc(l,1) search_loc(l,1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                %                     plot([search_loc(l,2) search_loc(l,2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                %                 end

                % If the SiC button is selected, plot the lines corresponding to its peak search intervals
            elseif strcmp(selectedButton.Text, 'SiC')
                plot([app.peak_window.sic(1) app.peak_window.sic(1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                plot([app.peak_window.sic(2) app.peak_window.sic(2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.SpectrumAxes)
                fill([app.peak_window.sic(1) app.peak_window.sic(2) app.peak_window.sic(2) app.peak_window.sic(1)], [0 0 1 1], app.fill_color, 'FaceAlpha',0.3, 'Parent', app.SpectrumAxes)
            end
        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Raman Spectrum.png');

            % Save the figure
            exportgraphics(app.SpectrumAxes, figure_name, 'Resolution', 300)
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            i = round(event.Value);
            view = app.SurfaceAxes.View;

            Y_Index = repmat(1:app.y_size, app.num_bands, 1);
            Wavenumber = repmat(app.wavenumber, [1 app.y_size]);
            Intensity = squeeze(app.dcube(:,i,:)).';

            %% 3D Mesh of Line Scan
            % Colored faces with white edges
            app.surface = mesh(Wavenumber, Y_Index, Intensity, 'Parent', app.SurfaceAxes);

            app.surface.FaceColor = 'interp';
            app.surface.EdgeColor = 'w';
            app.surface.EdgeAlpha = app.EdgeEditField.Value;

            app.SurfaceAxes.XLabel.String = '\textbf{Raman Shift (cm$^{-1}$)}';
            app.SurfaceAxes.YLabel.String = '\textbf{Y Index}';

            app.SurfaceAxes.XLim = [app.wavenumber(1) app.wavenumber(end)];
            app.SurfaceAxes.YLim = [1 app.y_size];

            app.SurfaceAxes.View = view;
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            j = round(event.Value);
            view = app.SurfaceAxes.View;

            X_Index = repmat(1:app.x_size, app.num_bands, 1);
            Wavenumber = repmat(app.wavenumber, [1 app.x_size]);
            Intensity = squeeze(app.dcube(j,:,:)).';

            %% 3D Mesh of Line Scan
            % Colored faces with white edges
            app.surface = mesh(X_Index, Wavenumber, Intensity, 'Parent', app.SurfaceAxes);

            app.surface.FaceColor = 'interp';
            app.surface.EdgeColor = 'w';
            app.surface.EdgeAlpha = app.EdgeEditField.Value;

            app.SurfaceAxes.XLabel.String = '\textbf{X Index}';
            app.SurfaceAxes.YLabel.String = '\textbf{Raman Shift (cm$^{-1}$)}';

            app.SurfaceAxes.XLim = [1 app.x_size];
            app.SurfaceAxes.YLim = [app.wavenumber(1) app.wavenumber(end)];

            app.SurfaceAxes.View = view;
        end

        % Value changed function: EdgeEditField
        function EdgeEditFieldValueChanged(app, event)
            app.surface.EdgeAlpha = app.EdgeEditField.Value;
        end

        % Button pushed function: SaveSurfaceButton
        function SaveSurfaceButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Raman 3D Plot.png');

            % Save the figure
            exportgraphics(app.SurfaceAxes, figure_name, 'Resolution', 300)
        end

        % Button pushed function: AnalyzePhasesButton
        function AnalyzePhasesButtonPushed(app, event)
            app.PhaseAxes.Visible = 'off';

            %% Match Spectra
            score_map(:,:,1) = sidsam(app.dcube, app.standard.B4C);
            score_map(:,:,2) = sidsam(app.dcube, app.standard.Si);
            score_map(:,:,3) = sidsam(app.dcube, app.standard.SiB6);
            score_map(:,:,4) = sidsam(app.dcube, app.standard.SiC);
            score_map(:,:,5) = sidsam(app.dcube, app.standard.SiBC);

            %% Phase Map
            [~,app.phase_map] = min(score_map,[],3);

            imagesc(app.PhaseAxes,app.phase_map)
            pbaspect(app.PhaseAxes,[app.ratio 1 1])

            app.PhaseAxes.Colormap = jet(5);

            ticks = linspace(1,5,5);
            labels = app.standard.Properties.VariableNames;

            colorbar(app.PhaseAxes,'Parent',app.PhasesTab,'Ticks',ticks, 'TickLabels',labels(2:end), 'YDir', 'reverse');
            clim(app.PhaseAxes, [0.5 5.5])

            app.PhaseAxes.XLim = [1 app.x_size];
            app.PhaseAxes.YLim = [1 app.y_size];

            app.PhaseAxes.Visible = 'on';
            app.AnalyzeStressButton.Enable = 'on';
        end

        % Button pushed function: HypercubeButton
        function HypercubeButtonPushed(app, event)
            wavelength = ( (app.lambda_excitation)^(-1) - (app.wavenumber ./ 10^7) ).^(-1);

            hcube = hypercube(app.dcube, wavelength);
            hyperspectralViewer(hcube);
        end

        % Button pushed function: SavePhasesButton
        function SavePhasesButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Raman Phase Map.png');

            % Save the figure
            exportgraphics(app.PhaseAxes, figure_name, 'Resolution', 300)
        end

        % Button pushed function: AnalyzeStressButton
        function AnalyzeStressButtonPushed(app, event)
            %% Parallel Processing
            if isempty(gcp('nocreate'))
                parpool('local')
            end

            %% Fit Peaks
            app.peak_fit = cell(app.y_size,app.x_size);

            % Add code to remove peak fits with poor goodness of fit

            [temp_phase_map, temp_wavenumber, temp_peak_window, temp_dcube, temp_first_guess, temp_fit_data] = ...
                deal(app.phase_map, app.wavenumber, app.peak_window, app.dcube, app.first_guess, app.peak_fit);

            tic
            for j = 1:app.y_size
                app.RowEditField.Value = j;
                pause(0.01)
                parfor i = 1:app.x_size
                    if temp_phase_map(j,i) == 1
                        [~,b4c2_start] = min(abs(temp_wavenumber - temp_peak_window.b4c2(1)));
                        [~,b4c2_end] = min(abs(temp_wavenumber - temp_peak_window.b4c2(2)));

                        [~,b4c4_start] = min(abs(temp_wavenumber - temp_peak_window.b4c4(1)));
                        [~,b4c4_end] = min(abs(temp_wavenumber - temp_peak_window.b4c4(2)));

                        signal2 = [temp_wavenumber(b4c2_start:b4c2_end) squeeze(temp_dcube(j,i,b4c2_start:b4c2_end))];
                        signal4 = [temp_wavenumber(b4c4_start:b4c4_end) squeeze(temp_dcube(j,i,b4c4_start:b4c4_end))];

                        [FitResults2, FitError2] = peakfit(signal2,0,0,2,1,0,3,temp_first_guess.b4c2,1,0,0);
                        [FitResults4, FitError4] = peakfit(signal4,0,0,1,1,0,3,temp_first_guess.b4c4,1,0,0);

                        FitError2 = [FitError2(2); FitError2(2)]
                        Fit = [FitResults2(:,2:5), FitError2; FitResults4(1,2:5), FitError4(2)];

                        temp_fit_data(j,i) = mat2cell(Fit, 3, 5);

                    elseif temp_phase_map(j,i) == 4
                        [~,sic_start] = min(abs(temp_wavenumber - temp_peak_window.sic(1)));
                        [~,sic_end] = min(abs(temp_wavenumber - temp_peak_window.sic(2)));

                        sic_signal = [temp_wavenumber(sic_start:sic_end) squeeze(temp_dcube(j,i,sic_start:sic_end))];

                        [FitResults, FitError] = peakfit(sic_signal,0,0,1,1,0,3,temp_first_guess.sic,1,0,0);

                        temp_fit_data(j,i) = mat2cell([FitResults(:,2:5) FitError(:,2)], 1, 5);

                    elseif temp_phase_map(j,i) == 5
                        [~,sibc1_start] = min(abs(temp_wavenumber - temp_peak_window.sibc1(1)));
                        [~,sibc1_end] = min(abs(temp_wavenumber - temp_peak_window.sibc1(2)));

                        [~,sibc2_start] = min(abs(temp_wavenumber - temp_peak_window.sibc2(1)));
                        [~,sibc2_end] = min(abs(temp_wavenumber - temp_peak_window.sibc2(2)));

                        [~,sibc4_start] = min(abs(temp_wavenumber - temp_peak_window.sibc4(1)));
                        [~,sibc4_end] = min(abs(temp_wavenumber - temp_peak_window.sibc4(2)));

                        [~,sibc5_start] = min(abs(temp_wavenumber - temp_peak_window.sibc5(1)));
                        [~,sibc5_end] = min(abs(temp_wavenumber - temp_peak_window.sibc5(2)));

                        signal1 = [temp_wavenumber(sibc1_start:sibc1_end) squeeze(temp_dcube(j,i,sibc1_start:sibc1_end))];
                        signal2 = [temp_wavenumber(sibc2_start:sibc2_end) squeeze(temp_dcube(j,i,sibc2_start:sibc2_end))];
                        signal4 = [temp_wavenumber(sibc4_start:sibc4_end) squeeze(temp_dcube(j,i,sibc4_start:sibc4_end))];
                        signal5 = [temp_wavenumber(sibc5_start:sibc5_end) squeeze(temp_dcube(j,i,sibc5_start:sibc5_end))];

                        [FitResults1, FitError1] = peakfit(signal1,0,0,1,1,0,3,temp_first_guess.sibc1,1,0,0);
                        [FitResults2, FitError2] = peakfit(signal2,0,0,2,1,0,3,temp_first_guess.sibc2,1,0,0);
                        [FitResults4, FitError4] = peakfit(signal4,0,0,1,1,0,3,temp_first_guess.sibc4,1,0,0);
                        [FitResults5, FitError5] = peakfit(signal5,0,0,1,1,0,3,temp_first_guess.sibc5,1,0,0);

                        FitError2 = [FitError2(2); FitError2(2)]
                        Fit = [FitResults1(1,2:5), FitError1(2); FitResults2(:,2:5), FitError2; FitResults4(1,2:5), FitError4(2); FitResults5(1,2:5), FitError5(2)];

                        temp_fit_data(j,i) = mat2cell(Fit, 5, 5);
                    end
                end
            end
            toc

            app.RowEditField.Value = 0;
            pause(0.01)

            app.peak_fit = temp_fit_data;

            %% Preallocate Vectors
            total(:,:,1) = sum(4 * (app.phase_map == 1),'all');
            total(:,:,2) = sum(2 * (app.phase_map == 2),'all');
            total(:,:,3) = sum(2 * (app.phase_map == 3),'all');
            total(:,:,4) = sum(2 * (app.phase_map == 4),'all');
            total(:,:,5) = sum(6 * (app.phase_map == 5),'all');
            total(:,:,6) = sum(2 * (app.phase_map == 0),'all');
            num_rows = sum(total,'all');

            [M, X, Y, H, Position, Height, FWHM, Area, Error] = deal(NaN(num_rows,1));

            table_row = 1;

            for j = 1:app.y_size
                for i = 1:app.x_size
                    if app.phase_map(j,i) == 1
                        height = 3;

                        M(table_row) = 1;
                        X(table_row) = i;
                        Y(table_row) = j;
                        H(table_row) = height;
                        Position(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,1);
                        Height(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,2);
                        FWHM(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,3);
                        Area(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,4);
                        Error(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,5);

                        table_row = table_row + height + 1;

                    elseif app.phase_map(j,i) == 2
                        height = 0;

                        M(table_row) = 2;
                        X(table_row) = i;
                        Y(table_row) = j;
                        H(table_row) = height;

                        table_row = table_row + 2;

                    elseif app.phase_map(j,i) == 3
                        height = 0;

                        M(table_row) = 3;
                        X(table_row) = i;
                        Y(table_row) = j;
                        H(table_row) = height;

                        table_row = table_row + 2;

                    elseif app.phase_map(j,i) == 4
                        height = 1;

                        M(table_row) = 4;
                        X(table_row) = i;
                        Y(table_row) = j;
                        H(table_row) = height;
                        Position(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,1);
                        Height(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,2);
                        FWHM(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,3);
                        Area(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,4);
                        Error(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,5);

                        table_row = table_row + height + 1;

                    elseif app.phase_map(j,i) == 5
                        height = 5;

                        M(table_row) = 5;
                        X(table_row) = i;
                        Y(table_row) = j;
                        H(table_row) = height;
                        Position(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,1);
                        Height(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,2);
                        FWHM(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,3);
                        Area(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,4);
                        Error(table_row:table_row + height - 1) = app.peak_fit{j,i}(:,5);

                        table_row = table_row + height + 1;

                    else
                        height = 0;

                        M(table_row) = 0;
                        X(table_row) = i;
                        Y(table_row) = j;
                        H(table_row) = height;

                        table_row = table_row + 2;
                    end
                end
            end

            %% Write Table
            peak_export = table(M, X, Y, H, Position, Height, FWHM, Area, Error);

            table_name = append(app.path, 'peak_export.txt');
            writetable(peak_export, table_name, 'Delimiter', 'tab')

            %
            app.PhaseDropDown.Enable = 'on';
            pause(0.01)

            app.pos_peak = NaN(app.y_size,app.x_size);

            for j = 1:app.y_size
                for i = 1:app.x_size
                    if app.phase_map(j,i) == 1
                        app.pos_peak(j,i) = app.peak_fit{j,i}(3,1);

                    elseif app.phase_map(j,i) == 5
                            app.pos_peak(j,i) = app.peak_fit{j,i}(4,1);
                    end
                end
            end
        end

        % Value changed function: PhaseDropDown
        function PhaseDropDownValueChanged(app, event)
            if strcmp(app.PhaseDropDown.Value, '')
                cla(app.StressAxes)

                app.PeakDropDown.Enable = 'off';
                app.PeakDropDown.Items = {''};
                app.PeakDropDown.Value = '';

            elseif strcmp(app.PhaseDropDown.Value, 'B4C')
                cla(app.StressAxes)

                app.PeakDropDown.Enable = 'on';
                app.PeakDropDown.Items = {'', '480', '520', '1080'};
                app.PeakDropDown.Value = '';

            elseif strcmp(app.PhaseDropDown.Value, 'Si')
                cla(app.StressAxes)

                app.PeakDropDown.Enable = 'on';
                app.PeakDropDown.Items = {'', '520'};
                app.PeakDropDown.Value = '';

            elseif strcmp(app.PhaseDropDown.Value, 'SiB6')
                cla(app.StressAxes)

                app.PeakDropDown.Enable = 'off';
                app.PeakDropDown.Items = {''};
                app.PeakDropDown.Value = '';

            elseif strcmp(app.PhaseDropDown.Value, 'SiC')
                cla(app.StressAxes)

                app.PeakDropDown.Enable = 'on';
                app.PeakDropDown.Items = {'', '960'};
                app.PeakDropDown.Value = '';

            elseif strcmp(app.PhaseDropDown.Value, 'SiBC')
                cla(app.StressAxes)

                app.PeakDropDown.Enable = 'on';
                app.PeakDropDown.Items = {'', '240', '480', '520', '1080', '1200'};
                app.PeakDropDown.Value = '';
            end
        end

        % Value changed function: PeakDropDown
        function PeakDropDownValueChanged(app, event)
            peak_map = NaN(app.y_size,app.x_size);
            app.StressAxes.Visible = 'on';

            %             peak_map(app.phase_map == 1) = app.fit_data{app.phase_map == 1}(3,1);

            if strcmp(app.PhaseDropDown.Value, 'B4C')
                if strcmp(app.PeakDropDown.Value, '')
                    cla(app.StressAxes)

                elseif strcmp(app.PeakDropDown.Value, '480')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 1
                                if app.peak_fit{j,i}(1,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(1,1);
                                end
                            end
                        end
                    end

                elseif strcmp(app.PeakDropDown.Value, '520')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 1
                                if app.peak_fit{j,i}(2,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(2,1);
                                end
                            end
                        end
                    end

                elseif strcmp(app.PeakDropDown.Value, '1080')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 1
                                if app.peak_fit{j,i}(3,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(3,1);
                                end
                            end
                        end
                    end
                end

            elseif strcmp(app.PhaseDropDown.Value, 'Si')
                if strcmp(app.PeakDropDown.Value, '')
                    cla(app.StressAxes)

                elseif strcmp(app.PeakDropDown.Value, '520')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 2
                                if app.peak_fit{j,i}(1,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(1,1);
                                end
                            end
                        end
                    end
                end

            elseif strcmp(app.PhaseDropDown.Value, 'SiB6')
                if strcmp(app.PeakDropDown.Value, '')
                    cla(app.StressAxes)
                end

                %                 if strcmp(app.PeakDropDown.Value, '480')
                %                     peak_map(app.phase_map == 3) = app.fit_data{app.phase_map == 3}(1,1);
                %                 end

            elseif strcmp(app.PhaseDropDown.Value, 'SiC')
                if strcmp(app.PeakDropDown.Value, '')
                    cla(app.StressAxes)

                elseif strcmp(app.PeakDropDown.Value, '960')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 4
                                if app.peak_fit{j,i}(1,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(1,1);
                                end
                            end
                        end
                    end
                end

            elseif strcmp(app.PhaseDropDown.Value, 'SiBC')
                if strcmp(app.PeakDropDown.Value, '')
                    cla(app.StressAxes)

                elseif strcmp(app.PeakDropDown.Value, '240')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 5
                                if app.peak_fit{j,i}(1,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(1,1);
                                end
                            end
                        end
                    end

                elseif strcmp(app.PeakDropDown.Value, '480')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 5
                                if app.peak_fit{j,i}(1,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(2,1);
                                end
                            end
                        end
                    end

                elseif strcmp(app.PeakDropDown.Value, '520')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 5
                                if app.peak_fit{j,i}(2,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(3,1);
                                end
                            end
                        end
                    end

                elseif strcmp(app.PeakDropDown.Value, '1080')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 5
                                if app.peak_fit{j,i}(3,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(4,1);
                                end
                            end
                        end
                    end

                elseif strcmp(app.PeakDropDown.Value, '1200')
                    for j = 1:app.y_size
                        for i = 1:app.x_size
                            if app.phase_map(j,i) == 5
                                if app.peak_fit{j,i}(4,5) > 0.3
                                    peak_map(j,i) = app.peak_fit{j,i}(5,1);
                                end
                            end
                        end
                    end
                end
            end

            stress_map = imagesc(app.StressAxes,peak_map);
            set(stress_map,'AlphaData',~isnan(peak_map))
            pbaspect(app.StressAxes,[app.ratio 1 1])

            app.StressAxes.XLim = [1 app.x_size];
            app.StressAxes.YLim = [1 app.y_size];

            colormap(app.StressAxes,'jet');
            colorbar(app.StressAxes,'Parent',app.StressTab)
            clim(app.StressAxes,'auto')

            colorbar_limits = app.StressAxes.CLim;
            app.C1EditField.Value = colorbar_limits(1);
            app.C2EditField.Value = colorbar_limits(2);
        end

        % Value changed function: C1EditField
        function C1EditFieldValueChanged(app, event)
            colorbar_limits = app.StressAxes.CLim;
            colorbar_limits(1) = app.C1EditField.Value;
            clim(app.StressAxes, colorbar_limits)
        end

        % Value changed function: C2EditField
        function C2EditFieldValueChanged(app, event)
            colorbar_limits = app.StressAxes.CLim;
            colorbar_limits(2) = app.C2EditField.Value;
            clim(app.StressAxes, colorbar_limits)
        end

        % Button pushed function: SaveStressButton
        function SaveStressButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Raman Stress Map - ', app.PhaseDropDown.Value, ' - ', app.PeakDropDown.Value, '.png');

            % Save the figure
            exportgraphics(app.StressAxes, figure_name, 'Resolution', 300)
        end

        % Button pushed function: HorizontalButton
        function HorizontalButtonPushed(app, event)
            app.HorizontalButton.BackgroundColor = [0.65,0.65,0.65];
            app.VerticalButton.BackgroundColor = [0.96,0.96,0.96];
            
            % Clear axes
            cla(app.AverageAxes)

            app.pos_mean = mean(app.pos_peak,2,'omitnan');
            app.pos_std = std(app.pos_peak,0,2,'omitnan');
            app.pos_mean = movmean(app.pos_mean, app.NEditField.Value);
            plot(app.pos_mean, 'b', 'LineWidth', 2, 'Parent', app.AverageAxes)
        end

        % Button pushed function: VerticalButton
        function VerticalButtonPushed(app, event)
            app.VerticalButton.BackgroundColor = [0.65,0.65,0.65];
            app.HorizontalButton.BackgroundColor = [0.96,0.96,0.96];
            
            % Clear axes
            cla(app.AverageAxes)

            app.pos_mean = mean(app.pos_peak,1,'omitnan');
            app.pos_std = std(app.pos_peak,0,1,'omitnan');
            app.pos_mean = movmean(app.pos_mean, app.NEditField.Value);
            plot(app.pos_mean, 'b', 'LineWidth', 2, 'Parent', app.AverageAxes)
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            % Clear axes
            cla(app.AverageAxes)

            app.pos_mean = mean(app.pos_peak,2,'omitnan');
            app.pos_mean = movmean(app.pos_mean, app.NEditField.Value);
            plot(app.pos_mean, 'b', 'LineWidth', 2, 'Parent', app.AverageAxes)
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            %% Write Table
            Index = (1:length(app.pos_mean))';
            Center = app.pos_mean;
            STD = app.pos_std;
            average_position = table(Index,Center,STD);
            
            table_name = append(app.path, 'average_position.txt');
            writetable(average_position, table_name, 'Delimiter', 'tab')
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Line Average Map.png');

            % Save the figure
            exportgraphics(app.AverageAxes, figure_name, 'Resolution', 300)
        end

        % Button pushed function: SelectGrainButton
        function SelectGrainButtonPushed(app, event)
            hold(app.GrainAxes, 'on')
            roi = images.roi.Polygon(app.GrainAxes);
            draw(roi)

            app.num_g = app.num_g + 1;
            [pos] = roi.Position;
            x = pos(:,1);
            y = pos(:,2);
            s = num2str(app.num_g);

            pshape = polyshape(x,y);
            [a,b] = centroid(pshape);
            text(a,b,s, 'FontSize', 24, 'FontWeight', 'bold', 'Color', 'w', 'Parent', app.GrainAxes)

            app.grain_data{app.num_g} = mat2cell(pos, length(x), 2);
        end

        % Button pushed function: SaveDataButton_2
        function SaveDataButton_2Pushed(app, event)
%             if ~exist('GrainInfo', 'dir')
%                 mkdir('GrainInfo', app.path);
%             end
            
            for i = 1:app.num_g
                pos = cell2mat(app.grain_data{i});
                x = pos(:,1);
                y = pos(:,2);
                roi = poly2mask(x,y,app.y_size,app.x_size);
                
                temp_roi = false(app.y_size,app.x_size,app.num_bands);
                temp_roi(:,:,app.band(2)) = roi;

                Grain(i,1) = i;
                Peak_480(i,1) = sum(app.dcube(temp_roi), 'all') / sum(roi, 'all');

                img_mask = repmat(roi, [1 1 3]);
                img = img_mask .* app.grain_map;

                s = num2str(i);
                figure_name = fullfile(app.path, ['GrainInfo/Grain ' s '.png']);
                imwrite(img, figure_name);
            end

            %% Write Table
            peak_intensity = table(Grain, Peak_480);
            
            table_name = append(app.path, 'GrainInfo/Grain_Data.txt');
            writetable(peak_intensity, table_name, 'Delimiter', 'tab')

            roi_pos = table(vertcat(app.grain_data{:}));
            
            table_name = append(app.path, 'GrainInfo/Grain_Location.txt');
            writetable(roi_pos, table_name, 'Delimiter', 'tab')
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'GrainInfo/Grain Map.png');

            % Save the figure
            exportgraphics(app.GrainAxes, figure_name, 'Resolution', 300)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.PeakFitEditField = uieditfield(app.DataTab, 'text');
            app.PeakFitEditField.Editable = 'off';
            app.LoadPeakFitButton = uibutton(app.DataTab, 'push');
            app.LoadPeakFitButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPeakFitButtonPushed, true);
            app.LoadPeakFitButton.Text = 'Load Peak Fit';
            app.DataEditField = uieditfield(app.DataTab, 'text');
            app.DataEditField.Editable = 'off';
            app.LoadDataButton = uibutton(app.DataTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.StandardEditField = uieditfield(app.DataTab, 'text');
            app.StandardEditField.Editable = 'off';
            app.LoadStandardButton = uibutton(app.DataTab, 'push');
            app.LoadStandardButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStandardButtonPushed, true);
            app.LoadStandardButton.Text = 'Load Standard';
            app.ImageEditField = uieditfield(app.DataTab, 'text');
            app.ImageEditField.Editable = 'off';
            app.LoadImageButton = uibutton(app.DataTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.Image = uiimage(app.DataTab);
            app.Image.Visible = 'off';
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.SpectrumAxes = uiaxes(app.SpectrumTab);
            app.SpectrumAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SpectrumSlider = uislider(app.SpectrumTab);
            app.SpectrumSlider.Limits = [1 100];
            app.SpectrumSlider.ValueChangingFcn = createCallbackFcn(app, @SpectrumSliderValueChanging, true);
            app.SpectrumSlider.Value = 1;
            app.SaveSpectrumButton = uibutton(app.SpectrumTab, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.OneUpButton = uibutton(app.SpectrumTab, 'push');
            app.OneUpButton.ButtonPushedFcn = createCallbackFcn(app, @OneUpButtonPushed, true);
            app.OneUpButton.Text = 'One Up';
            app.OneDownButton = uibutton(app.SpectrumTab, 'push');
            app.OneDownButton.ButtonPushedFcn = createCallbackFcn(app, @OneDownButtonPushed, true);
            app.OneDownButton.Text = 'One Down';
            app.IndexLabel = uilabel(app.SpectrumTab);
            app.IndexLabel.Text = {'Index: 1'; 'X: 1'; 'Y: 1'};
            app.ButtonGroup = uibuttongroup(app.SpectrumTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SiCButton = uitogglebutton(app.ButtonGroup);
            app.SiCButton.Text = 'SiC';
            app.SiB6Button = uitogglebutton(app.ButtonGroup);
            app.SiB6Button.Text = 'SiB6';
            app.SiButton = uitogglebutton(app.ButtonGroup);
            app.SiButton.Text = 'Si';
            app.B4CButton = uitogglebutton(app.ButtonGroup);
            app.B4CButton.Text = 'B4C';
            app.NoLinesButton = uitogglebutton(app.ButtonGroup);
            app.NoLinesButton.Text = 'No Lines';
            app.NoLinesButton.Value = true;
            app.SurfaceTab = uitab(app.TabGroup);
            app.SurfaceTab.Title = 'Surface';
            app.SurfaceAxes = uiaxes(app.SurfaceTab);
            title(app.SurfaceAxes, 'Raman 3D Spectrum')
            xlabel(app.SurfaceAxes, 'X Distance')
            ylabel(app.SurfaceAxes, 'Raman Shift')
            zlabel(app.SurfaceAxes, 'Intensity')
            app.SaveSurfaceButton = uibutton(app.SurfaceTab, 'push');
            app.SaveSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSurfaceButtonPushed, true);
            app.SaveSurfaceButton.Text = 'Save Surface';
            app.EdgeEditField = uieditfield(app.SurfaceTab, 'numeric');
            app.EdgeEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeEditFieldValueChanged, true);
            app.EdgeEditField.Value = 1;
            app.YSlider = uislider(app.SurfaceTab);
            app.YSlider.Limits = [1 100];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YSlider.Value = 1;
            app.XSlider = uislider(app.SurfaceTab);
            app.XSlider.Limits = [1 100];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.Value = 1;
            app.EditFieldLabel = uilabel(app.SurfaceTab);
            app.EditFieldLabel.Text = 'α:';
            app.PhasesTab = uitab(app.TabGroup);
            app.PhasesTab.Title = 'Phases';
            app.PhaseAxes = uiaxes(app.PhasesTab);
            app.PhaseAxes.XTick = [];
            app.PhaseAxes.YTick = [];
            app.PhaseAxes.ZTick = [];
            app.SavePhasesButton = uibutton(app.PhasesTab, 'push');
            app.SavePhasesButton.ButtonPushedFcn = createCallbackFcn(app, @SavePhasesButtonPushed, true);
            app.SavePhasesButton.Text = 'Save Phases';
            app.HypercubeButton = uibutton(app.PhasesTab, 'push');
            app.HypercubeButton.ButtonPushedFcn = createCallbackFcn(app, @HypercubeButtonPushed, true);
            app.HypercubeButton.Text = 'Hypercube';
            app.AnalyzePhasesButton = uibutton(app.PhasesTab, 'push');
            app.AnalyzePhasesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzePhasesButtonPushed, true);
            app.AnalyzePhasesButton.Text = 'Analyze Phases';
            app.StressTab = uitab(app.TabGroup);
            app.StressTab.Title = 'Stress';
            app.StressAxes = uiaxes(app.StressTab);
            app.StressAxes.XTick = [];
            app.StressAxes.YTick = [];
            app.StressAxes.ZTick = [];
            app.SaveStressButton = uibutton(app.StressTab, 'push');
            app.SaveStressButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStressButtonPushed, true);
            app.SaveStressButton.Text = 'Save Stress';
            app.C2EditField = uieditfield(app.StressTab, 'numeric');
            app.C2EditField.ValueChangedFcn = createCallbackFcn(app, @C2EditFieldValueChanged, true);
            app.C2EditFieldLabel = uilabel(app.StressTab);
            app.C2EditFieldLabel.Text = 'C2:';
            app.C1EditField = uieditfield(app.StressTab, 'numeric');
            app.C1EditField.ValueChangedFcn = createCallbackFcn(app, @C1EditFieldValueChanged, true);
            app.C1EditFieldLabel = uilabel(app.StressTab);
            app.C1EditFieldLabel.Text = 'C1:';
            app.PeakDropDown = uidropdown(app.StressTab);
            app.PeakDropDown.Items = {};
            app.PeakDropDown.ValueChangedFcn = createCallbackFcn(app, @PeakDropDownValueChanged, true);
            app.PeakDropDown.Enable = 'off';
            app.PeakDropDown.Value = {};
            app.PeakLabel = uilabel(app.StressTab);
            app.PeakLabel.Text = 'Peak:';
            app.PhaseDropDown = uidropdown(app.StressTab);
            app.PhaseDropDown.Items = {'', 'B4C', 'Si', 'SiB6', 'SiC', 'SiBC'};
            app.PhaseDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseDropDownValueChanged, true);
            app.PhaseDropDown.Enable = 'off';
            app.PhaseDropDown.Value = '';
            app.PhaseLabel = uilabel(app.StressTab);
            app.PhaseLabel.Text = 'Phase:';
            app.AnalyzeStressButton = uibutton(app.StressTab, 'push');
            app.AnalyzeStressButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeStressButtonPushed, true);
            app.AnalyzeStressButton.Enable = 'off';
            app.AnalyzeStressButton.Text = 'Analyze Stress';
            app.RowLabel = uilabel(app.StressTab);
            app.RowLabel.Text = 'Row:';
            app.RowEditField = uieditfield(app.StressTab, 'numeric');
            app.RowEditField.Editable = 'off';
            app.AverageTab = uitab(app.TabGroup);
            app.AverageTab.Title = 'Average';
            app.AverageAxes = uiaxes(app.AverageTab);
            xlabel(app.AverageAxes, 'Index')
            ylabel(app.AverageAxes, 'Peak Position')
            zlabel(app.AverageAxes, 'Z')
            app.SavePlotButton = uibutton(app.AverageTab, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.SaveDataButton = uibutton(app.AverageTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.VerticalButton = uibutton(app.AverageTab, 'push');
            app.VerticalButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalButtonPushed, true);
            app.VerticalButton.Text = 'Vertical';
            app.HorizontalButton = uibutton(app.AverageTab, 'push');
            app.HorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalButtonPushed, true);
            app.HorizontalButton.Text = 'Horizontal';
            app.NEditField = uieditfield(app.AverageTab, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 1;
            app.NEditFieldLabel = uilabel(app.AverageTab);
            app.NEditFieldLabel.Text = 'N:';
            app.GrainsTab = uitab(app.TabGroup);
            app.GrainsTab.Title = 'Grains';
            app.GrainAxes = uiaxes(app.GrainsTab);
            app.GrainAxes.XTick = [];
            app.GrainAxes.YTick = [];
            app.GrainAxes.ZTick = [];
            app.SaveImageButton = uibutton(app.GrainsTab, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.SaveDataButton_2 = uibutton(app.GrainsTab, 'push');
            app.SaveDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_2Pushed, true);
            app.SaveDataButton_2.Text = 'Save Data';
            app.SelectGrainButton = uibutton(app.GrainsTab, 'push');
            app.SelectGrainButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGrainButtonPushed, true);
            app.SelectGrainButton.Text = 'Select Grain';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end