classdef Raman_3D_Plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        YStepEditField       matlab.ui.control.NumericEditField
        YStepEditFieldLabel  matlab.ui.control.Label
        XStepEditField       matlab.ui.control.NumericEditField
        XStepEditFieldLabel  matlab.ui.control.Label
        EdgeAlphaEditField   matlab.ui.control.NumericEditField
        EdgeAlphaLabel       matlab.ui.control.Label
        YSlider              matlab.ui.control.Slider
        XSlider              matlab.ui.control.Slider
        SaveFigureButton     matlab.ui.control.Button
        LoadDataButton       matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path                    % location of Raman data
        x_size                  % width of the scan region
        y_size                  % height of the scan region

        trunc = [150, 1500];    % wavenumber range of tuncated data
        trunc_size              % number of data points spanning the range of truncated data
        map_data                % Three dimensional cell where (X, Y, 1) contains a column array of wavenumbers
                                % and (X, Y, 2) cointains a column array of intensities
        

        % Graph Labels
        title_text = '\textbf{Raman 3D Plot}';
        x_text = '\textbf{X Distance ($\mu$m)}';
        y_text = '\textbf{Y Distance ($\mu$m)}';
        shift_text = '\textbf{Raman Shift (cm$^{-1}$)}';
        intensity_text = '\textbf{Intensity}';

        surf                    % property to store surface
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            
            % Clear axes
            cla(app.UIAxes)

            % Restore default properties
            app.EdgeAlphaEditField.Value = 1;
            app.XSlider.Value = 1;
            app.YSlider.Value = 1;
            app.XStepEditField.Value = 1;
            app.YStepEditField.Value = 1;
            app.UIAxes.View = [0 90];

            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'});
            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
            
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [app.x_size, ~] = size(x);
            [app.y_size, ~] = size(y);
            [s_size, ~] = size(s);

            % Turn off the x and y slider if there scan is one dimensional
            if app.x_size == 1 || app.y_size == 1
                app.XSlider.Visible = 'off';
                app.YSlider.Visible = 'off';                
            
            % Set the slider limits
            else
                app.XSlider.Limits = [1 app.x_size];
                app.YSlider.Limits = [1 app.y_size];
            
                % Adjust major and minor x ticks if there are few x points
                if app.x_size < 50
                    app.XSlider.MinorTicks = 1:1:app.x_size;
                    app.XSlider.MajorTicks = 1:1:app.x_size;
                else
                    app.XSlider.MajorTicksMode = 'auto';
                    app.XSlider.MajorTickLabelsMode = 'auto';
                    app.XSlider.MinorTicksMode = 'auto';
                end

                % Adjust major and minor y ticks if there are few y points
                if app.y_size < 50
                    app.YSlider.MinorTicks = 1:1:app.y_size;
                    app.YSlider.MajorTicks = 1:1:app.y_size;
                else
                    app.YSlider.MajorTicksMode = 'auto';
                    app.YSlider.MajorTickLabelsMode = 'auto';
                    app.YSlider.MinorTicksMode = 'auto';
                end
            end
            
            %% Convert raw data to plottable format
            % Initialize the cell that stores the Raman data
            app.map_data = cell(app.x_size,app.y_size,2);

            % Determine the indices associated with the truncation start and stop
            [~,trunc_start] = min(abs(data(1:s_size,3) - app.trunc(2)));
            [~,trunc_end] = min(abs(data(1:s_size,3) - app.trunc(1)));

            app.trunc_size = trunc_end - trunc_start + 1;

            % Adjust the truncation start and stop so the values can be used with indexing variables a and b
            start_offset = trunc_start - 1;
            end_offset = s_size - trunc_end;
            
            for j = 1:app.y_size
                for i = 1:app.x_size
                    a = (i-1)*s_size + (j-1)*app.x_size*s_size + 1;
                    a = a + start_offset;

                    b = i*s_size + (j-1)*app.x_size*s_size;
                    b = b - end_offset;
        
                    % Store the truncated data and normalize the intensities
                    app.map_data(i,j,1) = mat2cell(data(b:-1:a,3),app.trunc_size,1);
                    app.map_data(i,j,2) = mat2cell(normalize(data(b:-1:a,4),1,'range'),app.trunc_size,1);
                end
            end

            % Store the wavenumber and intensity of the x data if the plot is a line scan in x
            if app.y_size == 1
                [Intensity,Wavenumber] = deal(zeros(app.trunc_size,app.x_size));
                for x = 1:app.x_size
                    Wavenumber(:,x) = app.map_data{x,1,1};
                    Intensity(:,x) = app.map_data{x,1,2};  

                    x_step = app.XStepEditField.Value;

                    d = 0:x_step:x_step * (app.x_size - 1);
                    X_Distance = repelem(d,app.trunc_size,1);
                end

            % Otherwise store the wavenumeber and intensity of the y data
            else
                [Intensity,Wavenumber] = deal(zeros(app.trunc_size,app.y_size));
                for y = 1:app.y_size
                    Wavenumber(:,y) = app.map_data{1,y,1};
                    Intensity(:,y) = app.map_data{1,y,2};

                    y_step = app.YStepEditField.Value;

                    d = 0:y_step:y_step * (app.y_size - 1);
                    Y_Distance = repelem(d,app.trunc_size,1);
                end
            end
                    
            %% 3D Mesh of Line Scan
            % Colored faces with white edges
            if app.y_size == 1
                app.surf = mesh(X_Distance, Wavenumber, Intensity, 'Parent', app.UIAxes);

                app.UIAxes.XLabel.String = app.x_text;
                app.UIAxes.YLabel.String = app.shift_text;

                app.UIAxes.XLim = [d(1) d(end)];
                app.UIAxes.YLim = [Wavenumber(1,1) Wavenumber(end,1)];

            else
                app.surf = mesh(Wavenumber, Y_Distance, Intensity, 'Parent', app.UIAxes);

                app.UIAxes.XLabel.String = app.shift_text;
                app.UIAxes.YLabel.String = app.y_text;

                app.UIAxes.XLim = [Wavenumber(1,1) Wavenumber(end,1)];
                app.UIAxes.YLim = [d(1) d(end)];
            end

            app.surf.FaceColor = 'interp';
            app.surf.EdgeColor = 'w';
            app.surf.EdgeAlpha = app.EdgeAlphaEditField.Value;
            
            app.UIAxes.Title.String = app.title_text;
            app.UIAxes.Title.Interpreter = 'latex';
            app.UIAxes.Title.FontSize = 18;

            app.UIAxes.XLabel.Interpreter = 'latex';
            app.UIAxes.XLabel.FontSize = 14;

            app.UIAxes.YLabel.Interpreter = 'latex';
            app.UIAxes.YLabel.FontSize = 14;
            
            app.UIAxes.ZLabel.String = app.intensity_text;
            app.UIAxes.ZLabel.Interpreter = 'latex';
            app.UIAxes.ZLabel.FontSize = 14;

            app.UIAxes.ZLim = [0 1];

            % Turn on axes
            app.UIAxes.Visible = 'on';
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            x = round(event.Value);

            view = app.UIAxes.View;

            [Intensity,Wavenumber] = deal(zeros(app.trunc_size,app.y_size));
            for y = 1:app.y_size
                Wavenumber(:,y) = app.map_data{x,y,1};
                Intensity(:,y) = app.map_data{x,y,2};  
            end

            y_step = app.YStepEditField.Value;

            d = 0:y_step:y_step * (app.y_size - 1);
            Y_Distance = repelem(d,app.trunc_size,1);

            %% 3D Mesh of Line Scan
            % Colored faces with white edges
            app.surf = mesh(Wavenumber, Y_Distance, Intensity, 'Parent', app.UIAxes);

            app.surf.FaceColor = 'interp';
            app.surf.EdgeColor = 'w';
            app.surf.EdgeAlpha = app.EdgeAlphaEditField.Value;

            app.UIAxes.XLabel.String = app.shift_text;
            app.UIAxes.YLabel.String = app.y_text;            

            app.UIAxes.XLim = [Wavenumber(1,1) Wavenumber(end,1)];
            app.UIAxes.YLim = [d(1) d(end)];

            app.UIAxes.View = view;
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            y = round(event.Value);

            view = app.UIAxes.View;

            [Intensity,Wavenumber] = deal(zeros(app.trunc_size,app.x_size));
            for x = 1:app.x_size
                Wavenumber(:,x) = app.map_data{x,y,1};
                Intensity(:,x) = app.map_data{x,y,2};  
            end

            x_step = app.XStepEditField.Value;

            d = 0:x_step:x_step * (app.x_size - 1);
            X_Distance = repelem(d,app.trunc_size,1);

            %% 3D Mesh of Line Scan
            % Colored faces with white edges
            app.surf = mesh(X_Distance, Wavenumber, Intensity, 'Parent', app.UIAxes);

            app.surf.FaceColor = 'interp';
            app.surf.EdgeColor = 'w';
            app.surf.EdgeAlpha = app.EdgeAlphaEditField.Value;

            app.UIAxes.XLabel.String = app.x_text;
            app.UIAxes.YLabel.String = app.shift_text;

            app.UIAxes.XLim = [d(1) d(end)];
            app.UIAxes.YLim = [Wavenumber(1,1) Wavenumber(end,1)];

            app.UIAxes.View = view;
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            % Save the figure
            exportgraphics(app.UIAxes, fullfile(app.path,'Raman_3D_Plot.png'), 'Resolution', 300)
        end

        % Value changed function: EdgeAlphaEditField
        function EdgeAlphaEditFieldValueChanged(app, event)
            % Update the transparency of edges to improve visibility
            app.surf.EdgeAlpha = app.EdgeAlphaEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [1 100];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.Value = 1;
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [1 100];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YSlider.Value = 1;
            app.EdgeAlphaLabel = uilabel(app.UIFigure);
            app.EdgeAlphaLabel.Text = 'Edge Alpha:';
            app.EdgeAlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdgeAlphaEditField.Limits = [0 1];
            app.EdgeAlphaEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeAlphaEditFieldValueChanged, true);
            app.EdgeAlphaEditField.Value = 1;
            app.XStepEditFieldLabel = uilabel(app.UIFigure);
            app.XStepEditFieldLabel.Text = 'X Step:';
            app.XStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.XStepEditField.Limits = [1 Inf];
            app.XStepEditField.Value = 1;
            app.YStepEditFieldLabel = uilabel(app.UIFigure);
            app.YStepEditFieldLabel.Text = 'Y Step:';
            app.YStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.YStepEditField.Limits = [1 Inf];
            app.YStepEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_3D_Plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end