classdef Raman_Heat_Map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SaveFigureButton  matlab.ui.control.Button
        LoadImageButton   matlab.ui.control.Button
        LoadDataButton    matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path                    % location of Raman data (saved figures will be stored here)
        saved_figs = 0;         % counter for number of saved figures
    
        trunc_range = [150, 1500];    % wavenumber range of truncated data
        map_data                % three dimensional cell where (X, Y, 1) contains a column array of wavenumbers
                                % and (X, Y, 2) cointains a column array of intensities
        
        peak_center = 520;      % stress free peak position                               
        peak_range = [2 3];     % delta from peak center for determining background intensity [l_of_ctr, r_of_ctr]
        rel_intensity = 5       % condition to determine if peak is present (ratio of peak intensity to background intensity)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            
            % Clear axes
            cla(app.UIAxes)
            
            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'});
            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
 
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [x_size, ~] = size(x);
            [y_size, ~] = size(y);
            [s_size, ~] = size(s);

            %% Convert raw data to usable format
            % Initialize the cell that stores the Raman data
            app.map_data = cell(x_size,y_size,2);

            % Determine the indices associated with the truncation start and stop
            [~,trunc_start] = min(abs(data(1:s_size,3) - app.trunc_range(2)));
            [~,trunc_end] = min(abs(data(1:s_size,3) - app.trunc_range(1)));

            trunc_size = trunc_end - trunc_start + 1;

            % Adjust the truncation start and stop so the values can be used with indexing variables a and b
            start_offset = trunc_start - 1;
            end_offset = s_size - trunc_end;
            
            for j = 1:y_size
                for i = 1:x_size
                    a = (i-1)*s_size + (j-1)*x_size*s_size + 1;
                    a = a + start_offset;

                    b = i*s_size + (j-1)*x_size*s_size;
                    b = b - end_offset;
        
                    % Store the truncated data and normalize the intensities
                    app.map_data(i,j,1) = mat2cell(data(b:-1:a,3),trunc_size,1);
                    app.map_data(i,j,2) = mat2cell(normalize(data(b:-1:a,4),1,'range'),trunc_size,1);
                end
            end

            %% Create scaled color map
            C = zeros(x_size, y_size, 1);

            Wavenumber = app.map_data{1,1,1};

            [~,start_index] = min(abs(Wavenumber - (app.peak_center - app.peak_range(1))));
            [~,end_index] = min(abs(Wavenumber - (app.peak_center + app.peak_range(2))));

            for j = 1:y_size
                for i = 1:x_size
                    % Find index of peak maximum and peak intensity
                    [peak_intensity, max_index] = max(app.map_data{i,j,2}(start_index:end_index, 1), [], 1);

                    peak_wavenumber = app.map_data{i,j,1}(max_index,1);

                    [~,start_wavenumber] = min(abs(Wavenumber - (peak_wavenumber - app.peak_range(1))));
                    [~,end_wavenumber] = min(abs(Wavenumber - (peak_wavenumber + app.peak_range(2))));

                    % Find background inensity
                    [background_intensity, ~] = min(app.map_data{i,j,2}(start_wavenumber:end_wavenumber, 1), [], 1);

                    % If relative instensity condition is met store the
                    % difference between the location of the peak maximum
                    % and the peak center, else set value to NaN
                    if (peak_intensity / background_intensity) < app.rel_intensity
                        C(i,j,1) = NaN;
                    else
                        C(i,j,1) = peak_wavenumber - app.peak_center;
                    end

                end
            end

            imagesc(C, 'Parent', app.UIAxes)
            colorbar

%             function h = imagesc2 ( img_data )
%                 % plotting data. Removing and scaling axes (this is for image plotting)
%                 h = imagesc(img_data);
%                 axis image off
% 
%                 % setting alpha values
%                 if ndims( img_data ) == 2
%                   set(h, 'AlphaData', ~isnan(img_data))
%                 elseif ndims( img_data ) == 3
%                   set(h, 'AlphaData', ~isnan(img_data(:, :, 1)))
%                 end
%                 
%                 if nargout < 1
%                   clear h
%                 end
%             end

            app.UIAxes.Visible = 'on';
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %% Image selection prompt
            [image_file, image_path] = uigetfile({'*.tif';'*.tiff';'*.png';'lpg';'*.jpeg'},'Select image of map region',data_path);
            image_name = fullfile(image_path, image_file);
            image = imread(image_name);
            
%             %% Coordinate selection prompt
%             figure()
%             hold on
%             imshow(image)
%             [x_coord, y_coord] = ginput(2);
%             line(x_coord, y_coord, 'Color', 'r', 'LineWidth', 2)
%             
%             %%
%             % Line Intersections
%             % Determine image size and generate scale factor
%             [y_pixels, x_pixels, ~] = size(image);
%             scale = [x_pixels/(x_size), y_pixels/(y_size)];
%             
%             % Scale x-values and determine min/max
%             x_pos = x_coord / scale(1);
%             x_min = min(x_pos, [], 1);
%             x_max = max(x_pos, [], 1);
%             
%             % This step may not be necessary because the y-axis on the raman is flipped?
%             y_coord = y_pixels - y_coord;
%             
%             % Scale y-values and determine min/max
%             y_pos = y_coord / scale(2);
%             y_min = min(y_pos, [], 1);
%             y_max = max(y_pos, [], 1);
%             
%             % Create a grid from the min/max values
%             x = floor(x_min) : 1 : ceil(x_max);
%             y = floor(y_min) : 1 : ceil(y_max);
%             
%             % Determine line equation from selected coordinates
%             m = (y_pos(2) - y_pos(1)) / (x_pos(2) - x_pos(1));
%             b = (y_pos(1) - m * x_pos(1));
%             
%             hrz = @(y, m, b) [(y - b)./m; y];
%             vrt = @(x, m, b) [x; m.*x + b];
%             
%             % Round up or down intercepts based on slope
%             if m < 0
%                 h_int = ceil(hrz(y, m, b)');
%                 v_int = floor(vrt(x, m, b)');
%                 hv_int = [h_int; v_int];
%             else
%                 h_int = ceil(hrz(y, m, b)');
%                 v_int = ceil(vrt(x, m, b)');
%                 hv_int = [h_int; v_int];
%             end
%             
%             % Remove values that lie outside the grid boundary
%             ex_bdry = find(hv_int(:,1) < floor(x_min) | hv_int(:,1) < 1);
%             ex_bdry = [find(hv_int(:,1) > ceil(x_max) | hv_int(:,1) > x_size); ex_bdry];
%             ex_bdry = [find(hv_int(:,2) < y_min | hv_int(:,2) < 1 ); ex_bdry];
%             ex_bdry = [find(hv_int(:,2) > y_max | hv_int(:,2) > y_size); ex_bdry];
%             hv_int(ex_bdry,:) = [];
%             
%             % Remove duplicate values
%             if m < 0
%                 unq = unique(hv_int,'rows');
%                 srtd = flip(sortrows(unq,[1 2],{'descend' 'ascend'}));
%             else
%                 srtd = unique(hv_int,'rows');
%             end
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            % Increment the number of saved figures counter
            app.saved_figs = app.saved_figs + 1;

            % Generate the new figure name
            figure_name = append(app.path, 'Raman Plot - ', int2str(app.saved_figs), '.png');

            % Save the figure
            exportgraphics(app.UIAxes, figure_name, 'Resolution', 300)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_Heat_Map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end