classdef Raman_Phase_Map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        HypercubeButton     matlab.ui.control.Button
        LoadDataButton      matlab.ui.control.Button
        SavePhaseMapButton  matlab.ui.control.Button
        LoadStandardButton  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path                                % location of Raman data (saved figures will be stored here)
        saved_figs = 0;                     % counter for number of saved figures

        lambda_excitation = 532;            % excitation laser wavelength in nm

        wave_start = 130;                   % spectral bands to be analyzed
        wave_end = 1500;                    % spectral bands to be analyzed
        
        standard                            % table of standard spectra 

        wavenumber                          % column vector of spectral bands
        dcube                               % Y x X x Lambda data cube
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadStandardButton
        function LoadStandardButtonPushed(app, event)
            %% Standard Selection Prompt
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            [file, temp_path] = uigetfile({'*.txt'});

            standard_name = fullfile(temp_path, file);
            app.standard = readtable(standard_name);

            app.standard = flipud(app.standard);

            [~, start_index] = min(abs(app.standard.Wavenumber - app.wave_start));
            [~, end_index] = min(abs(app.standard.Wavenumber - app.wave_end));

            app.standard = app.standard(start_index:end_index, :);

            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            
            % Clear axes
            cla(app.UIAxes)
            set(app.UIAxes, 'color', 'none')
            
            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'});

            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
 
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [x_size, ~] = size(x);
            [y_size, ~] = size(y);
            [s_size, ~] = size(s);

            %% Convert raw data to usable format
            % Store Raman shift
            app.wavenumber = data(s_size:-1:1,3);    
            
            % Initialize the cell that stores the Raman data
            app.dcube = zeros(y_size,x_size,s_size);
            
            for j = 1:y_size
                for i = 1:x_size
                    a = (i-1)*s_size + (j-1)*x_size*s_size + 1;
                    b = i*s_size + (j-1)*x_size*s_size;
            
                    app.dcube(j,i,:) = data(b:-1:a,4);
                end
            end
            
            [~, start_index] = min(abs(app.wavenumber - app.wave_start));
            [~, end_index] = min(abs(app.wavenumber - app.wave_end));
            
            app.wavenumber = app.wavenumber(start_index:end_index);
            app.dcube = normalize(app.dcube(:,:,start_index:end_index),3,'range');

            %% Match Spectra
            score_map(:,:,1) = sidsam(app.dcube, app.standard.B4C);
            score_map(:,:,2) = sidsam(app.dcube, app.standard.Si);
            score_map(:,:,3) = sidsam(app.dcube, app.standard.SiB6);
            score_map(:,:,4) = sidsam(app.dcube, app.standard.SiC);

            %% Class Map
            [~,phase_map] = min(score_map,[],3);
            
            imagesc(app.UIAxes,phase_map)

            app.UIAxes.Colormap = jet(4);
            
            ticks = linspace(1,4,4);
            labels = app.standard.Properties.VariableNames;
            
            colorbar(app.UIAxes,'Parent',app.UIFigure,'Ticks',ticks, 'TickLabels',labels(2:end));
            clim(app.UIAxes, [0.5 4.5])

            app.UIAxes.Title.String = '';

            app.UIAxes.XLabel.String = '';
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = [];
            app.UIAxes.XColor = 'none';

            app.UIAxes.YLabel.String = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.YColor = 'none';

            app.UIAxes.Visible = 'on';
        end

        % Button pushed function: HypercubeButton
        function HypercubeButtonPushed(app, event)
            % Convert Raman shift to Raman wavelength
            wavelength = ( (app.lambda_excitation)^(-1) - (app.wavenumber ./ 10^7) ).^(-1);

            hcube = hypercube(app.dcube, wavelength);
            hyperspectralViewer(hcube);
        end

        % Button pushed function: SavePhaseMapButton
        function SavePhaseMapButtonPushed(app, event)
            % Increment the number of saved figures counter
            app.saved_figs = app.saved_figs + 1;

            % Generate the new figure name
            figure_name = append(app.path, 'Raman Phase Map - ', int2str(app.saved_figs), '.png');

            % Save the figure
            export_fig(figure_name,'-transparent','-m5');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.LoadStandardButton = uibutton(app.UIFigure, 'push');
            app.LoadStandardButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStandardButtonPushed, true);
            app.LoadStandardButton.Text = 'Load Standard';
            app.SavePhaseMapButton = uibutton(app.UIFigure, 'push');
            app.SavePhaseMapButton.ButtonPushedFcn = createCallbackFcn(app, @SavePhaseMapButtonPushed, true);
            app.SavePhaseMapButton.Text = 'Save Phase Map';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.HypercubeButton = uibutton(app.UIFigure, 'push');
            app.HypercubeButton.ButtonPushedFcn = createCallbackFcn(app, @HypercubeButtonPushed, true);
            app.HypercubeButton.Text = 'Hypercube';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_Phase_Map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end