classdef Raman_Plot_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        IndexLabel        matlab.ui.control.Label
        ButtonGroup       matlab.ui.container.ButtonGroup
        SiButton          matlab.ui.control.ToggleButton
        SiB6Button        matlab.ui.control.ToggleButton
        SiCButton         matlab.ui.control.ToggleButton
        B4CButton         matlab.ui.control.ToggleButton
        NoLinesButton     matlab.ui.control.ToggleButton
        SaveFigureButton  matlab.ui.control.Button
        LoadDataButton    matlab.ui.control.Button
        Slider            matlab.ui.control.Slider
        OneDownButton     matlab.ui.control.Button
        OneUpButton       matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        path                    % location of Raman data (saved figures will be stored here)
        saved_figs = 0;         % counter for number of saved figures

        lambda_excitation = 532; % excitation laser wavelength in nm

        % Specify spectral bands to be analyzed
        wave_start = 535.74;
        wave_end = 595;
    
        x_size                  % width of the scan region, used to determine x and y from the index
        ij                      % total size of the scan (the product of x and y)

        wavelength
        dcube               % Three dimensional cell where (X, Y, 1) contains a column array of wavenumbers
                                % and (X, Y, 2) cointains a column array of intensities
        
        first_guess = struct('b4c1', [539.63 1.55 541.33 1.28], 'b4c2', [546.00 0.32 547.52 0.37], ...
                             'b4c3', [553.33 1.55 555.58 1.55 556.68 0.96 557.50 0.64], 'b4c4', [560.79 0.80 561.98 1.28 564.66 2.51], ...
                             'si', [547.4 1], 'sic', [560.76 0.283]);

        % Graph Labels
        title_text = '\textbf{Raman Viewer}';
        x_text = '\textbf{Wavelength (nm)}';
        y_text = '\textbf{Intensity}';
    
        pt                      % property to store plot so it can be deleted when the slider position is changed
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            
            % Clear axes
            cla(app.UIAxes)

            % Restore default properties
            Index = 'Index: 1';
            IJ = 'X: 1; Y: 1';
            app.IndexLabel.Text = {Index, IJ};
            app.Slider.Value = 1;

            app.ButtonGroup.SelectedObject = app.NoLinesButton;
            
            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'});
            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
            
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [app.x_size, ~] = size(x);
            [y_size, ~] = size(y);
            [s_size, ~] = size(s);
            
            % Determine map area (total number of indices)
            app.ij = app.x_size * y_size;

            app.Slider.Limits = [1 app.ij];

            %% Convert raw data to usable format
            % Store Raman shift
            app.wavelength = data(s_size:-1:1,3);
            
            % Convert Raman shift to Raman wavelength
            app.wavelength = ( (app.lambda_excitation)^(-1) - (app.wavelength ./ 10^7) ).^(-1);
            
            % Initialize the cell that stores the Raman data
            app.dcube = zeros(y_size,app.x_size,s_size);
            
            for j = 1:y_size
                for i = 1:app.x_size
                    a = (i-1)*s_size + (j-1)*app.x_size*s_size + 1;
                    b = i*s_size + (j-1)*app.x_size*s_size;
            
                    app.dcube(j,i,:) = data(b:-1:a,4);
                end
            end
            
            [~, start_index] = min(abs(app.wavelength - app.wave_start));
            [~, end_index] = min(abs(app.wavelength - app.wave_end));
            
            app.wavelength = app.wavelength(start_index:end_index);
            app.dcube = normalize(app.dcube(:,:,start_index:end_index),3,'range');            
            
            %% Start Plot
            hold(app.UIAxes,'on')

            app.pt = plot(app.wavelength, squeeze(app.dcube(1,1,:)), 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
            
            % Title
            app.UIAxes.Title.String = app.title_text;
            app.UIAxes.Title.Interpreter = 'latex';
            app.UIAxes.Title.FontSize = 18;

            % X Axis
            app.UIAxes.XLabel.String = app.x_text;
            app.UIAxes.XLabel.Interpreter = 'latex';
            app.UIAxes.XLabel.FontSize = 14;

            % Y Axis
            app.UIAxes.YLabel.String = app.y_text;
            app.UIAxes.YLabel.Interpreter = 'latex';
            app.UIAxes.YLabel.FontSize = 14;

            % Axis Limits
            app.UIAxes.XLim = [app.wavelength(1) app.wavelength(end)];
%             app.UIAxes.YLim = [0 1];    

            % Turn on axes
            app.UIAxes.Visible = 'on';
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            % Increment the number of saved figures counter
            app.saved_figs = app.saved_figs + 1;

            % Generate the new figure name
            figure_name = append(app.path, 'Raman Plot - ', int2str(app.saved_figs), '.png');

            % Save the figure
            exportgraphics(app.UIAxes, figure_name, 'Resolution', 300)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % Delete old plot
            delete(app.pt)

            % Obtain updated index
            index = round(event.Value);

            % Determine x and y from the index
            x = mod(index, app.x_size);
            if x == 0
                x = app.x_size;
            end
            y = ceil(index / app.x_size);
            
            % Update the index label
            Index = num2str(index); Index = append('Index: ', Index);
            X = num2str(x); 
            Y = num2str(y);
            IJ = append('X: ', X, '; Y: ', Y);
            app.IndexLabel.Text = {Index, IJ};
        
            % Update the plot
            app.pt = plot(app.wavelength, squeeze(app.dcube(y,x,:)), 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
        end

        % Button pushed function: OneDownButton
        function OneDownButtonPushed(app, event)
            % Delete old plot
            delete(app.pt)

            % Obtain current index and increment one down if the index is greater than 1
            index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
            if index > 1
                index = index - 1;
            end
    
            % Update the slider with the new index
            app.Slider.Value = index;
    
            % Determine x and y from the index
            x = mod(index, app.x_size);
            if x == 0
                x = app.x_size;
            end
            y = ceil(index / app.x_size);
            
            % Update the index label
            Index = num2str(index); Index = append('Index: ', Index);
            X = num2str(x); 
            Y = num2str(y);
            IJ = append('X: ', X, '; Y: ', Y);
            app.IndexLabel.Text = {Index, IJ};
        
            % Update the plot
            app.pt = plot(app.wavelength, squeeze(app.dcube(y,x,:)), 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
        end

        % Button pushed function: OneUpButton
        function OneUpButtonPushed(app, event)
            % Delete old plot
            delete(app.pt)

            % Obtain current index and increment one up if the index is less than the plot area
            index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
            if index < app.ij
                index = index + 1;
            end
    
            % Update the slider with the new index
            app.Slider.Value = index;
    
            % Determine x and y from the index
            x = mod(index, app.x_size);
            if x == 0
                x = app.x_size;
            end
            y = ceil(index / app.x_size);
            
            % Update the index label
            Index = num2str(index); Index = append('Index: ', Index);
            X = num2str(x); 
            Y = num2str(y);
            IJ = append('X: ', X, '; Y: ', Y);
            app.IndexLabel.Text = {Index, IJ};
        
            % Update the plot
            app.pt = plot(app.wavelength, squeeze(app.dcube(y,x,:)), 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % Store selected button
            selectedButton = app.ButtonGroup.SelectedObject;

            % Clear axes
            cla(app.UIAxes)

            % Obtain current index
            index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
    
            % Determine x and y from the index
            x = mod(index, app.x_size);
            if x == 0
                x = app.x_size;
            end
            y = ceil(index / app.x_size);
            
            % Update the plot
            app.pt = plot(app.wavelength, squeeze(app.dcube(y,x,:)), 'b', 'LineWidth', 2, 'Parent', app.UIAxes);

            % If the B4C button is selected, plot the lines corresponding to its peak search intervals
            if strcmp(selectedButton.Text, 'B4C') 
                search_loc = [460,500; 510,550; 700,760; 1040,1120];

                for l = 1:size(search_loc,1)
                    plot([search_loc(l,1) search_loc(l,1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                    plot([search_loc(l,2) search_loc(l,2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                end
            end

            % If the SiC button is selected, plot the lines corresponding to its peak search intervals
            if strcmp(selectedButton.Text, 'SiC')
                search_loc = [770,810; 950,990];

                for l = 1:size(search_loc,1)
                    plot([search_loc(l,1) search_loc(l,1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                    plot([search_loc(l,2) search_loc(l,2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                end
            end

            % If the SiB6 button is selected, plot the lines corresponding to its peak search intervals
            if strcmp(selectedButton.Text, 'SiB6')
                search_loc = [770,810; 950,990];

                for l = 1:size(search_loc,1)
                    plot([search_loc(l,1) search_loc(l,1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                    plot([search_loc(l,2) search_loc(l,2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                end
            end

            % If the Si button is selected, plot the lines corresponding to its peak search intervals
            if strcmp(selectedButton.Text, 'Si')
                search_loc = [485,600];

                for l = 1:size(search_loc,1)
                    plot([search_loc(l,1) search_loc(l,1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                    plot([search_loc(l,2) search_loc(l,2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % Obtain updated index
            index = round(event.Value);

            % Determine x and y from the index
            x = mod(index, app.x_size);
            if x == 0
                x = app.x_size;
            end
            y = ceil(index / app.x_size);
            
            % Update the index label
            Index = num2str(index); Index = append('Index: ', Index);
            X = num2str(x); 
            Y = num2str(y);
            IJ = append('X: ', X, '; Y: ', Y);
            app.IndexLabel.Text = {Index, IJ};
        
%             % Si
%             [~,peak_start] = min(abs(app.wavelength - 545));
%             [~,peak_end] = min(abs(app.wavelength - 550));
% 
%             signal = [app.wavelength(peak_start:peak_end) squeeze(app.dcube(y,x,peak_start:peak_end))];
% 
%             figure
%             [FitResults,FitError] = peakfit(signal,0,0,1,15,1.5,3,app.first_guess.si,0);
% 
%             figure
%             [FitResults,FitError] = peakfit(signal,0,0,1,15,1.6,3,app.first_guess.si,0);      
% 
%             figure
%             [FitResults,FitError] = peakfit(signal,0,0,1,15,1.7,3,app.first_guess.si,0);    
% 
%             figure
%             [FitResults,FitError] = peakfit(signal,0,0,1,15,1.8,3,app.first_guess.si,0); 
            
% 
%             % SiC
%             [~,peak_start] = min(abs(app.wavelength - 557.97));
%             [~,peak_end] = min(abs(app.wavelength - 563.57));
% 
%             figure
%             signal = [app.wavelength(peak_start:peak_end) squeeze(app.dcube(y,x,peak_start:peak_end))];
%             [FitResults,FitError] = peakfit(signal,0,0,1,1,0,3,app.first_guess.sic,1);

            % B4C
%             [~,peak_start] = min(abs(app.wavelength - 537.72));
%             [~,peak_end] = min(abs(app.wavelength - 571.53));

            [~,peak_start(1)] = min(abs(app.wavelength - 536.3));
            [~,peak_end(1)] = min(abs(app.wavelength - 544.0));

            [~,peak_start(2)] = min(abs(app.wavelength - 545.0));
            [~,peak_end(2)] = min(abs(app.wavelength - 548.8));

%             [~,peak_start(3)] = min(abs(app.wavelength - 550.0));
%             [~,peak_end(3)] = min(abs(app.wavelength - 558.9));

            [~,peak_start(4)] = min(abs(app.wavelength - 558.9));
            [~,peak_end(4)] = min(abs(app.wavelength - 571.53));

            signal1 = [app.wavelength(peak_start(1):peak_end(1)) squeeze(app.dcube(y,x,peak_start(1):peak_end(1)))];
            signal2 = [app.wavelength(peak_start(2):peak_end(2)) squeeze(app.dcube(y,x,peak_start(2):peak_end(2)))];
%             signal3 = [app.wavelength(peak_start(3):peak_end(3)) squeeze(app.dcube(y,x,peak_start(3):peak_end(3)))];
            signal4 = [app.wavelength(peak_start(4):peak_end(4)) squeeze(app.dcube(y,x,peak_start(4):peak_end(4)))];

            figure
            [FitResults,FitError] = peakfit(signal1,0,0,2,1,0,10,app.first_guess.b4c1,1);

            figure
            [FitResults,FitError] = peakfit(signal2,0,0,2,1,0,10,app.first_guess.b4c2,1);

%             figure
%             [FitResults,FitError] = peakfit(signal3,0,0,4,1,0,10,app.first_guess.b4c3,1);

            figure
            [FitResults,FitError] = peakfit(signal4,0,0,3,1,0,10,app.first_guess.b4c4,1);


            % center and window are set to 0 to fit the entire signal
            % NumPeaks is 11 for B4C
            % peakshape is 1 for unconstrained Gaussian
            % extra might be 0 or 1
            % NumTrials is 1
            % start specifies the firstguess in the form [position1 width1 position2 width2 ...]
            % BaselineMode is 0 since the baseline does not need to be subtracted
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OneUpButton = uibutton(app.UIFigure, 'push');
            app.OneUpButton.ButtonPushedFcn = createCallbackFcn(app, @OneUpButtonPushed, true);
            app.OneUpButton.Text = 'One Up';
            app.OneDownButton = uibutton(app.UIFigure, 'push');
            app.OneDownButton.ButtonPushedFcn = createCallbackFcn(app, @OneDownButtonPushed, true);
            app.OneDownButton.Text = 'One Down';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NoLinesButton = uitogglebutton(app.ButtonGroup);
            app.NoLinesButton.Text = 'No Lines';
            app.NoLinesButton.Value = true;
            app.B4CButton = uitogglebutton(app.ButtonGroup);
            app.B4CButton.Text = 'B4C';
            app.SiCButton = uitogglebutton(app.ButtonGroup);
            app.SiCButton.Text = 'SiC';
            app.SiB6Button = uitogglebutton(app.ButtonGroup);
            app.SiB6Button.Text = 'SiB6';
            app.SiButton = uitogglebutton(app.ButtonGroup);
            app.SiButton.Text = 'Si';
            app.IndexLabel = uilabel(app.UIFigure);
            app.IndexLabel.Text = {'Index: 1'; 'X:1; Y:1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_Plot_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end