classdef Raman_Stress_Map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RelativeIntesityEditField  matlab.ui.control.NumericEditField
        RelativeIntesityLabel      matlab.ui.control.Label
        SaveFigureButton           matlab.ui.control.Button
        LoadDataButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path                        % location of Raman data (saved figures will be stored here)
        saved_figs = 0;             % counter for number of saved figures
    
        trunc_range = [150, 1500];  % wavenumber range of truncated data
        x_size
        y_size
        map_data                    % three dimensional cell where (X, Y, 1) contains a column array of wavenumbers
                                    % and (X, Y, 2) cointains a column array of intensities
        

        intensity_ratio             % Store peak intensity, background intensity and delta between peak max and peak center
        peak_delta
        
        rel_intensity               % condition to determine if peak is present (ratio of peak intensity to background intensity)

        %% Silicon
%         peak_center = 520;         % stress free peak position                               
%         peak_range = [30 80];     % delta from peak center for determining background intensity [l_of_ctr, r_of_ctr]
%         stress_multiplier = -250;    % number of MPa per wavenumber shift

        %% Boron Carbide
        peak_center = 1090;         % stress free peak position                               
        peak_range = [190 130];     % delta from peak center for determining background intensity [l_of_ctr, r_of_ctr]
        stress_multiplier = -300;    % number of MPa per wavenumber shift
        
        %% SiB6
%         peak_center = 520;         % stress free peak position                               
%         peak_range = [30 80];     % delta from peak center for determining background intensity [l_of_ctr, r_of_ctr]
%         stress_multiplier = -250;    % number of MPa per wavenumber shift     

        %% SiC
%         peak_center = 785;         % stress free peak position                               
%         peak_range = [35 30];     % delta from peak center for determining background intensity [l_of_ctr, r_of_ctr]
%         stress_multiplier = -280;    % number of MPa per wavenumber shift

%         peak_center = 970;         % stress free peak position                               
%         peak_range = [25 20];     % delta from peak center for determining background intensity [l_of_ctr, r_of_ctr]
%         stress_multiplier = -280;    % number of MPa per wavenumber shift
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            
            % Clear axes
            cla(app.UIAxes)
            set(app.UIAxes, 'color', 'none')
            
            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'});
            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
 
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [app.x_size, ~] = size(x);
            [app.y_size, ~] = size(y);
            [s_size, ~] = size(s);

            %% Convert raw data to usable format
            % Initialize the cell that stores the Raman data
            app.map_data = cell(app.x_size,app.y_size,2);

            % Determine the indices associated with the truncation start and stop
            [~,trunc_start] = min(abs(data(1:s_size,3) - app.trunc_range(2)));
            [~,trunc_end] = min(abs(data(1:s_size,3) - app.trunc_range(1)));

            trunc_size = trunc_end - trunc_start + 1;

            % Adjust the truncation start and stop so the values can be used with indexing variables a and b
            start_offset = trunc_start - 1;
            end_offset = s_size - trunc_end;
            
            for j = 1:app.y_size
                for i = 1:app.x_size
                    a = (i-1)*s_size + (j-1)*app.x_size*s_size + 1;
                    a = a + start_offset;

                    b = i*s_size + (j-1)*app.x_size*s_size;
                    b = b - end_offset;
        
                    % Store the truncated data and normalize the intensities
                    app.map_data(i,j,1) = mat2cell(data(b:-1:a,3),trunc_size,1);
                    app.map_data(i,j,2) = mat2cell(normalize(data(b:-1:a,4),1,'range'),trunc_size,1);
                end
            end

            %% Create color map
            [app.intensity_ratio, app.peak_delta] = deal(zeros(app.x_size, app.y_size));

            app.rel_intensity = app.RelativeIntesityEditField.Value;
            wavenumber = app.map_data{1,1,1};

            [~,start_index] = min(abs(wavenumber - (app.peak_center - app.peak_range(1))));
            [~,end_index] = min(abs(wavenumber - (app.peak_center + app.peak_range(2))));

            for j = 1:app.y_size
                for i = 1:app.x_size
                    % Find index of peak maximum and peak intensity
                    [peak_intensity, max_index] = max(app.map_data{i,j,2}(start_index:end_index, 1), [], 1);
                    max_index = start_index + max_index - 1;

                    peak_wavenumber = app.map_data{i,j,1}(max_index,1);

                    [~,start_wavenumber] = min(abs(wavenumber - (peak_wavenumber - app.peak_range(1))));
                    [~,end_wavenumber] = min(abs(wavenumber - (peak_wavenumber + app.peak_range(2))));

                    % Find background inensity
                    [background_intensity, ~] = min(app.map_data{i,j,2}(start_wavenumber:end_wavenumber, 1), [], 1);

                    app.intensity_ratio(i,j) = peak_intensity / background_intensity;
                    app.peak_delta(i,j) = peak_wavenumber - app.peak_center;
                end
            end

            % If relative instensity condition is met store the difference between the location of the peak maximum
            % and the peak center, else value is NaN
            C = NaN(app.x_size, app.y_size);
            C(app.intensity_ratio > app.rel_intensity) = app.stress_multiplier .* app.peak_delta(app.intensity_ratio > app.rel_intensity);

            % Transpose matrix to match Raman image
            C = C.';

            heat_map = imagesc(app.UIAxes, C);
            set(heat_map,'AlphaData',~isnan(C))
            colormap(app.UIAxes,'jet')
            
%             lim = max(abs(C),[],'all');

            % Negative values are compression. Positive values are tension. Units are in MPa
            color_bar = colorbar(app.UIAxes,'southoutside','Parent',app.UIFigure);
%             color_bar.Limits = [-lim lim];
            color_bar.FontSize = 18;

            app.UIAxes.Title.String = '';

            app.UIAxes.XLabel.String = '';
            app.UIAxes.XLim = [1 app.x_size];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = [];
            app.UIAxes.XColor = 'none';

            app.UIAxes.YLabel.String = '';
            app.UIAxes.YLim = [1 app.y_size];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.YColor = 'none';

            app.UIAxes.Visible = 'on';
        end

        % Value changed function: RelativeIntesityEditField
        function RelativeIntesityEditFieldValueChanged(app, event)
            app.UIAxes.Visible = 'off';
            app.rel_intensity = app.RelativeIntesityEditField.Value;
            
            C = NaN(app.x_size, app.y_size);
            C(app.intensity_ratio > app.rel_intensity) = app.stress_multiplier .* app.peak_delta(app.intensity_ratio > app.rel_intensity);

            C = C.';

            heat_map = imagesc(app.UIAxes, C);
            set(heat_map,'AlphaData',~isnan(C))
            colormap(app.UIAxes,'jet')

%             lim = max(abs(C),[],'all');

            % Negative values are compression. Positive values are tension. Units are in MPa
            color_bar = colorbar(app.UIAxes,'southoutside','Parent',app.UIFigure);
%             color_bar.Limits = [-lim lim];
            color_bar.FontSize = 18;

            app.UIAxes.Visible = 'on';
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            % Increment the number of saved figures counter
            app.saved_figs = app.saved_figs + 1;

            % Generate the new figure name
            figure_name = append(app.path, 'Raman Stress Map - ', int2str(app.saved_figs), '.png');

            % Save the figure
            export_fig(figure_name,'-transparent','-m5');
%             exportgraphics(app.UIAxes,figure_name,'Resolution',300)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.RelativeIntesityLabel = uilabel(app.UIFigure);
            app.RelativeIntesityLabel.Text = 'Relative Intesity:';
            app.RelativeIntesityEditField = uieditfield(app.UIFigure, 'numeric');
            app.RelativeIntesityEditField.Limits = [1 Inf];
            app.RelativeIntesityEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeIntesityEditFieldValueChanged, true);
            app.RelativeIntesityEditField.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_Stress_Map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end